classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        right_close           matlab.ui.control.Button
        right_open            matlab.ui.control.Button
        right_call_1          matlab.ui.control.Button
        right_call_2          matlab.ui.control.Button
        right_call_3          matlab.ui.control.Button
        left_close            matlab.ui.control.Button
        left_open             matlab.ui.control.Button
        left_call_1           matlab.ui.control.Button
        left_call_2           matlab.ui.control.Button
        left_call_3           matlab.ui.control.Button
        right_signal          matlab.ui.control.Label
        left_signal           matlab.ui.control.Label
        inner_right_showcase  matlab.ui.control.Image
        inner_left_showcase   matlab.ui.control.Image
        r_r_3                 matlab.ui.control.Label
        r_l_3                 matlab.ui.control.Label
        r_r_2                 matlab.ui.control.Label
        r_l_2                 matlab.ui.control.Label
        r_r_1                 matlab.ui.control.Label
        r_l_1                 matlab.ui.control.Label
        image6                matlab.ui.control.Image
        right_first_digit     matlab.ui.control.Label
        left_first_digit      matlab.ui.control.Label
        right_second_digit    matlab.ui.control.Label
        left_second_digit     matlab.ui.control.Label
        right_third_digit     matlab.ui.control.Label
        left_third_digit      matlab.ui.control.Label
        left_ele_up           matlab.ui.control.Label
        left_digit            matlab.ui.control.Label
        left_ele_down         matlab.ui.control.Label
        right_ele_up          matlab.ui.control.Label
        right_digit           matlab.ui.control.Label
        right_ele_down        matlab.ui.control.Label
        l_r_3                 matlab.ui.control.Label
        l_l_3                 matlab.ui.control.Label
        l_r_2                 matlab.ui.control.Label
        l_l_2                 matlab.ui.control.Label
        l_r_1                 matlab.ui.control.Label
        l_l_1                 matlab.ui.control.Label
        first_Button          matlab.ui.control.Button
        second_Button_down    matlab.ui.control.Button
        second_Button_up      matlab.ui.control.Button
        third_Button          matlab.ui.control.Button
        image5                matlab.ui.control.Image
        left_shaft            matlab.ui.control.EditField
        right_shaft           matlab.ui.control.EditField
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
    end

    
    properties (Access = public)
        controler;
        double_sig = [uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0),uint64(0)];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: second_Button_down
        function second_Button_downPushed(app, event)
            % Send data from back-end
            if app.controler.sig_list(3) == 0
                app.controler.scheduler(3);
                app.second_Button_down.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.controler.hold_list(3) == 0 && app.controler.left_ele.current_state == 4
                    app.controler.sig_list(3) = 0;
                    app.controler.hold_list(3) = -1;
                    app.controler.sig_list(5) = 1;
                    app.controler.hold_list(5) = 0;
                elseif app.controler.hold_list(3) == 1 && app.controler.right_ele.current_state == 4
                    app.controler.sig_list(3) = 0;
                    app.controler.hold_list(3) = -1;
                    app.controler.sig_list(13) = 1;
                    app.controler.hold_list(13) = 1;
                end
            end
        end

        % Button pushed function: third_Button
        function third_ButtonPushed(app, event)
            if app.controler.sig_list(1) == 0
                app.controler.scheduler(1);
                app.third_Button.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.controler.hold_list(1) == 0 && app.controler.left_ele.current_state == 4
                    app.controler.sig_list(1) = 0;
                    app.controler.hold_list(1) = -1;
                    app.controler.sig_list(5) = 1;
                    app.controler.hold_list(5) = 0;
                elseif app.controler.hold_list(1) == 1 && app.controler.right_ele.current_state == 4
                    app.controler.sig_list(1) = 0;
                    app.controler.hold_list(1) = -1;
                    app.controler.sig_list(13) = 1;
                    app.controler.hold_list(13) = 1;
                end
            end
        end

        % Button pushed function: first_Button
        function first_ButtonPushed(app, event)
            if app.controler.sig_list(4) == 0
                app.controler.scheduler(4);
                app.first_Button.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.controler.hold_list(4) == 0 && app.controler.left_ele.current_state == 4
                    app.controler.sig_list(4) = 0;
                    app.controler.hold_list(4) = -1;
                    app.controler.sig_list(5) = 1;
                    app.controler.hold_list(5) = 0;
                elseif app.controler.hold_list(4) == 1 && app.controler.right_ele.current_state == 4
                    app.controler.sig_list(4) = 0;
                    app.controler.hold_list(4) = -1;
                    app.controler.sig_list(13) = 1;
                    app.controler.hold_list(13) = 1;
                end
            end
        end

        % Button pushed function: second_Button_up
        function second_Button_upPushed(app, event)
            if app.controler.sig_list(2) == 0
                app.controler.scheduler(2);
                app.second_Button_up.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.controler.hold_list(2) == 0 && app.controler.left_ele.current_state == 4
                    app.controler.sig_list(2) = 0;
                    app.controler.hold_list(2) = -1;
                    app.controler.sig_list(5) = 1;
                    app.controler.hold_list(5) = 0;
                elseif app.controler.hold_list(2) == 1 && app.controler.right_ele.current_state == 4
                    app.controler.sig_list(2) = 0;
                    app.controler.hold_list(2) = -1;
                    app.controler.sig_list(13) = 1;
                    app.controler.hold_list(13) = 1;
                end
            end
        end

        % Button pushed function: left_open
        function left_openButtonPushed(app, event)
            app.controler.scheduler(5);
            app.controler.left_inner_p.inner_access();
            app.left_open.BackgroundColor = [0.00,1.00,0.00];
        end

        % Button pushed function: left_close
        function left_closeButtonPushed(app, event)
            if app.controler.left_ele.current_state == 5
                app.controler.scheduler(6);
                app.controler.left_inner_p.inner_access();
                app.left_close.BackgroundColor = [0.00,1.00,0.00];
            end
        end

        % Button pushed function: left_call_3
        function left_call_3ButtonPushed(app, event)
            if app.controler.sig_list(7) == 0
                app.controler.scheduler(7);
                app.controler.left_inner_p.inner_access();
                app.left_call_3.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(7) == uint64(0)
                    app.double_sig(7) = tic; % 初始化计时器
                elseif app.controler.hold_list(7) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(7));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(7) = 0;
                        app.left_call_3.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(7) = uint64(0); % 重置计时器
                    else
                        app.double_sig(7) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: left_call_2
        function left_call_2ButtonPushed(app, event)
            if app.controler.sig_list(8) == 0
                app.controler.scheduler(8);
                app.controler.left_inner_p.inner_access();
                app.left_call_2.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(8) == uint64(0)
                    app.double_sig(8) = tic; % 初始化计时器
                elseif app.controler.hold_list(8) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(8));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(8) = 0;
                        app.left_call_2.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(8) = uint64(0); % 重置计时器
                    else
                        app.double_sig(8) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: left_call_1
        function left_call_1ButtonPushed(app, event)
            if app.controler.sig_list(9) == 0
                app.controler.scheduler(9);
                app.controler.left_inner_p.inner_access();
                app.left_call_1.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(9) == uint64(0)
                    app.double_sig(9) = tic; % 初始化计时器
                elseif app.controler.hold_list(9) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(9));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(9) = 0;
                        app.left_call_1.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(9) = uint64(0); % 重置计时器
                    else
                        app.double_sig(9) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: right_call_3
        function right_call_3ButtonPushed(app, event)
            if app.controler.sig_list(10) == 0
                app.controler.scheduler(10);
                app.controler.right_inner_p.inner_access();
                app.right_call_3.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(10) == uint64(0)
                    app.double_sig(10) = tic; % 初始化计时器
                elseif app.controler.hold_list(10) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(10));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(10) = 0;
                        app.right_call_3.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(10) = uint64(0); % 重置计时器
                    else
                        app.double_sig(10) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: right_call_2
        function right_call_2ButtonPushed(app, event)
            if app.controler.sig_list(11) == 0
                app.controler.scheduler(11);
                app.controler.right_inner_p.inner_access();
                app.right_call_2.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(11) == uint64(0)
                    app.double_sig(11) = tic; % 初始化计时器
                elseif app.controler.hold_list(11) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(11));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(11) = 0;
                        app.right_call_2.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(11) = uint64(0); % 重置计时器
                    else
                        app.double_sig(11) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: right_call_1
        function right_call_1ButtonPushed(app, event)
            if app.controler.sig_list(12) == 0
                app.controler.scheduler(12);
                app.controler.right_inner_p.inner_access();
                app.right_call_1.BackgroundColor = [0.00,1.00,0.00];
            else
                if app.double_sig(12) == uint64(0)
                    app.double_sig(12) = tic; % 初始化计时器
                elseif app.controler.hold_list(12) == -1
                    % 检查是否在规定时间内双击
                    tt = toc(app.double_sig(12));
                    if tt <= 0.5
                        % 执行双击函数
                        app.controler.sig_list(12) = 0;
                        app.right_call_1.BackgroundColor = [0.96,0.96,0.96];
                        app.double_sig(12) = uint64(0); % 重置计时器
                    else
                        app.double_sig(12) = uint64(0); % 重新开始计时
                    end
                end
            end
        end

        % Button pushed function: right_open
        function right_openButtonPushed(app, event)
            app.controler.scheduler(13);
            app.controler.right_inner_p.inner_access();
            app.right_open.BackgroundColor = [0.00,1.00,0.00];
        end

        % Button pushed function: right_close
        function right_closeButtonPushed(app, event)
            if app.controler.right_ele.current_state == 5
                app.controler.scheduler(14);
                app.controler.right_inner_p.inner_access();
                app.right_close.BackgroundColor = [0.00,1.00,0.00];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.controler.delete_timer();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'elevator.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'elevator.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'elevator.jpg';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'elevator.jpg';
            app.right_shaft = uieditfield(app.UIFigure, 'text');
            app.left_shaft = uieditfield(app.UIFigure, 'text');
            app.image5 = uiimage(app.UIFigure);
            app.image5.ImageSource = 'elevator.jpg';
            app.third_Button = uibutton(app.UIFigure, 'push');
            app.third_Button.ButtonPushedFcn = createCallbackFcn(app, @third_ButtonPushed, true);
            app.third_Button.Text = '三层 ↓';
            app.second_Button_up = uibutton(app.UIFigure, 'push');
            app.second_Button_up.ButtonPushedFcn = createCallbackFcn(app, @second_Button_upPushed, true);
            app.second_Button_up.Text = '二层 ↑';
            app.second_Button_down = uibutton(app.UIFigure, 'push');
            app.second_Button_down.ButtonPushedFcn = createCallbackFcn(app, @second_Button_downPushed, true);
            app.second_Button_down.Text = '二层 ↓';
            app.first_Button = uibutton(app.UIFigure, 'push');
            app.first_Button.ButtonPushedFcn = createCallbackFcn(app, @first_ButtonPushed, true);
            app.first_Button.Text = '一层 ↑';
            app.l_l_1 = uilabel(app.UIFigure);
            app.l_l_1.Text = '';
            app.l_r_1 = uilabel(app.UIFigure);
            app.l_r_1.Text = '';
            app.l_l_2 = uilabel(app.UIFigure);
            app.l_l_2.Text = '';
            app.l_r_2 = uilabel(app.UIFigure);
            app.l_r_2.Text = '';
            app.l_l_3 = uilabel(app.UIFigure);
            app.l_l_3.Text = '';
            app.l_r_3 = uilabel(app.UIFigure);
            app.l_r_3.Text = '';
            app.right_ele_down = uilabel(app.UIFigure);
            app.right_ele_down.Text = '↓';
            app.right_digit = uilabel(app.UIFigure);
            app.right_digit.Text = '1';
            app.right_ele_up = uilabel(app.UIFigure);
            app.right_ele_up.Text = '↑';
            app.left_ele_down = uilabel(app.UIFigure);
            app.left_ele_down.Text = '↓';
            app.left_digit = uilabel(app.UIFigure);
            app.left_digit.Text = '1';
            app.left_ele_up = uilabel(app.UIFigure);
            app.left_ele_up.Text = '↑';
            app.left_third_digit = uilabel(app.UIFigure);
            app.left_third_digit.Text = '1';
            app.right_third_digit = uilabel(app.UIFigure);
            app.right_third_digit.Text = '1';
            app.left_second_digit = uilabel(app.UIFigure);
            app.left_second_digit.Text = '1';
            app.right_second_digit = uilabel(app.UIFigure);
            app.right_second_digit.Text = '1';
            app.left_first_digit = uilabel(app.UIFigure);
            app.left_first_digit.Text = '1';
            app.right_first_digit = uilabel(app.UIFigure);
            app.right_first_digit.Text = '1';
            app.image6 = uiimage(app.UIFigure);
            app.image6.ImageSource = 'elevator.jpg';
            app.r_l_1 = uilabel(app.UIFigure);
            app.r_l_1.Text = '';
            app.r_r_1 = uilabel(app.UIFigure);
            app.r_r_1.Text = '';
            app.r_l_2 = uilabel(app.UIFigure);
            app.r_l_2.Text = '';
            app.r_r_2 = uilabel(app.UIFigure);
            app.r_r_2.Text = '';
            app.r_l_3 = uilabel(app.UIFigure);
            app.r_l_3.Text = '';
            app.r_r_3 = uilabel(app.UIFigure);
            app.r_r_3.Text = '';
            app.inner_left_showcase = uiimage(app.UIFigure);
            app.inner_left_showcase.ImageSource = 'elevator.jpg';
            app.inner_right_showcase = uiimage(app.UIFigure);
            app.inner_right_showcase.ImageSource = 'elevator.jpg';
            app.left_signal = uilabel(app.UIFigure);
            app.left_signal.Text = '内选信号left';
            app.right_signal = uilabel(app.UIFigure);
            app.right_signal.Text = '内选信号right';
            app.left_call_3 = uibutton(app.UIFigure, 'push');
            app.left_call_3.ButtonPushedFcn = createCallbackFcn(app, @left_call_3ButtonPushed, true);
            app.left_call_3.Text = '内呼3';
            app.left_call_2 = uibutton(app.UIFigure, 'push');
            app.left_call_2.ButtonPushedFcn = createCallbackFcn(app, @left_call_2ButtonPushed, true);
            app.left_call_2.Text = '内呼2';
            app.left_call_1 = uibutton(app.UIFigure, 'push');
            app.left_call_1.ButtonPushedFcn = createCallbackFcn(app, @left_call_1ButtonPushed, true);
            app.left_call_1.Text = '内呼1';
            app.left_open = uibutton(app.UIFigure, 'push');
            app.left_open.ButtonPushedFcn = createCallbackFcn(app, @left_openButtonPushed, true);
            app.left_open.Text = '开门';
            app.left_close = uibutton(app.UIFigure, 'push');
            app.left_close.ButtonPushedFcn = createCallbackFcn(app, @left_closeButtonPushed, true);
            app.left_close.Text = '关门';
            app.right_call_3 = uibutton(app.UIFigure, 'push');
            app.right_call_3.ButtonPushedFcn = createCallbackFcn(app, @right_call_3ButtonPushed, true);
            app.right_call_3.Text = '内呼3';
            app.right_call_2 = uibutton(app.UIFigure, 'push');
            app.right_call_2.ButtonPushedFcn = createCallbackFcn(app, @right_call_2ButtonPushed, true);
            app.right_call_2.Text = '内呼2';
            app.right_call_1 = uibutton(app.UIFigure, 'push');
            app.right_call_1.ButtonPushedFcn = createCallbackFcn(app, @right_call_1ButtonPushed, true);
            app.right_call_1.Text = '内呼1';
            app.right_open = uibutton(app.UIFigure, 'push');
            app.right_open.ButtonPushedFcn = createCallbackFcn(app, @right_openButtonPushed, true);
            app.right_open.Text = '开门';
            app.right_close = uibutton(app.UIFigure, 'push');
            app.right_close.ButtonPushedFcn = createCallbackFcn(app, @right_closeButtonPushed, true);
            app.right_close.Text = '关门';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end