classdef app_define < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_21    matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Button_22    matlab.ui.control.Button
        B1           matlab.ui.control.StateButton
        B2           matlab.ui.control.StateButton
        B3           matlab.ui.control.StateButton
        B4           matlab.ui.control.StateButton
        B5           matlab.ui.control.StateButton
        B6           matlab.ui.control.StateButton
        B7           matlab.ui.control.StateButton
        B8           matlab.ui.control.StateButton
        B9           matlab.ui.control.StateButton
        B10          matlab.ui.control.StateButton
        play         matlab.ui.control.Button
        Chess_Board  matlab.ui.container.ButtonGroup
        GridLayout   matlab.ui.container.GridLayout
        Button_20    matlab.ui.control.StateButton
        Button_19    matlab.ui.control.StateButton
        Button_18    matlab.ui.control.StateButton
        Button_17    matlab.ui.control.StateButton
        Button_16    matlab.ui.control.StateButton
        Button_15    matlab.ui.control.StateButton
        Button_14    matlab.ui.control.StateButton
        Button_13    matlab.ui.control.StateButton
        Button_12    matlab.ui.control.StateButton
        Button_11    matlab.ui.control.StateButton
        Button_10    matlab.ui.control.StateButton
        Button_9     matlab.ui.control.StateButton
        Button_8     matlab.ui.control.StateButton
        Button_7     matlab.ui.control.StateButton
        Button_6     matlab.ui.control.StateButton
        Button_5     matlab.ui.control.StateButton
        Button_4     matlab.ui.control.StateButton
        Button_3     matlab.ui.control.StateButton
        Button_2     matlab.ui.control.StateButton
        Button       matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        chessboard % Description
        operating_button
        % the space the button put
        %4*1 matrix
        B1_space
        %2*1 matrix
        B2_space
        B3_space
        B4_space
        B5_space
        B6_space
        B7_space
        B8_space
        %1*1 matrix
        B9_space
        B10_space
        B1_put = false
        B2_put = false
        B3_put = false
        B4_put = false
        B5_put = false
        B6_put = false
        B7_put = false
        B8_put = false
        B9_put = false
        B10_put = false
        backend
    end
    
    methods (Access = public)
        function init_define(app,board,back)
            %give it an initial button
            app.operating_button = 0;
            app.chessboard = board;
            matrix = zeros(5,4);
            app.backend = back;
            %given it a 
            app.chessboard.init_board(matrix)
            app.B1.Value = 0;
            app.B2.Value = 0;
            app.B3.Value = 0;
            app.B4.Value = 0;
            app.B5.Value = 0;
            app.B6.Value = 0;
            app.B7.Value = 0;
            app.B8.Value = 0;
            app.B9.Value = 0;
            app.B10.Value = 0;
            app.B1_space = 0;
            app.B2_space = 0;
            app.B3_space = 0;
            app.B4_space = 0;
            app.B5_space = 0;
            app.B6_space = 0;
            app.B7_space = 0;
            app.B8_space = 0;
            app.B9_space = 0;
            app.B10_space = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: B1
        function B1ValueChanged(app, event)
            value = app.B1.Value;
            %the operating button is 1
            %2*2
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 1;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_2(app.B1_space,1) 
                app.B1_space = 0;
                app.B1_put = false;
                app.operating_button = 0;
            end
            
        end

        % Value changed function: B2
        function B2ValueChanged(app, event)
            value = app.B2.Value;
            %the operating button is 2
            %1*2
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 2;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_1(app.B2_space,2)    
                app.B2_space = 0;
                app.B2_put = false;
                app.operating_button = 0;
            end
            
        end

        % Value changed function: B3
        function B3ValueChanged(app, event)
            value = app.B3.Value;
            %the operating button is 3
            %2*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 3;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_1(app.B3_space,3)   
                app.B3_space = 0;
                app.B3_put = false;
                app.operating_button = 0;
            end
        end

        % Value changed function: B4
        function B4ValueChanged(app, event)
            value = app.B4.Value;
            %the operating button is 4
            %2*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 4;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_1(app.B4_space,4) 
                app.B4_space = 0;
                app.B4_put = false;
                app.operating_button = 0;
            end
        end

        % Value changed function: B5
        function B5ValueChanged(app, event)
            value = app.B5.Value;
            %the operating button is 5
            %2*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 5;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_1(app.B5_space,5) 
                app.B5_space = 0;
                app.B5_put = false;
                app.operating_button = 0;
            end
            
        end

        % Value changed function: B6
        function B6ValueChanged(app, event)
            value = app.B6.Value;
            %the operating button is 6
            %2*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 6;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button2_1(app.B6_space,6) 
                app.B6_space = 0;
                app.B6_put = false;
                app.operating_button = 0;
            end         
        end

        % Value changed function: B7
        function B7ValueChanged(app, event)
            value = app.B7.Value;
            %the operating button is 7
            %1*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 7;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button1_1(app.B7_space,7) 
                app.B7_space = 0;
                app.B7_put = false;
                app.operating_button = 0;
            end           
        end

        % Value changed function: B8
        function B8ValueChanged(app, event)
            value = app.B8.Value;
            %the operating button is 8
            %1*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 8;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button1_1(app.B8_space,8) 
                app.B8_space = 0;
                app.B8_put = false;
                app.operating_button = 0;
            end              
        end

        % Value changed function: B9
        function B9ValueChanged(app, event)
            value = app.B9.Value;
            %the operating button is 9
            %1*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 9;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button1_1(app.B9_space,9) 
                app.B9_space = 0;
                app.B9_put = false;
                app.operating_button = 0;
            end                     
        end

        % Value changed function: B10
        function B10ValueChanged(app, event)
            value = app.B10.Value;
            %the operating button is 10
            %1*1
            if value == 1
                app.backend.release_chess(app.operating_button)
                app.operating_button = 10;
            elseif value == 0
                %we should release the button if we give it space
                app.backend.release_chess(app.operating_button)
                app.backend.release_button1_1(app.B10_space,10) 
                app.B10_space = 0;
                app.B10_put = false;
                app.operating_button = 0;
            end                      
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value == 1
                app.backend.put_chess(1,1,1)
                app.operating_button = 0;
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            if value == 1
                app.backend.put_chess(2,1,2)
                app.operating_button = 0;
            end    
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            if value == 1
                app.backend.put_chess(3,1,3)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;
            if value == 1
                app.backend.put_chess(4,1,4)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            value = app.Button_5.Value;
            if value == 1
                app.backend.put_chess(5,2,1)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
            if value == 1
                app.backend.put_chess(6,2,2)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            value = app.Button_7.Value;
            if value == 1
                app.backend.put_chess(7,2,3)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Value;
            if value == 1
                app.backend.put_chess(8,2,4)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            value = app.Button_9.Value;
            if value == 1
                app.backend.put_chess(9,3,1)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            value = app.Button_10.Value;
            if value == 1
                app.backend.put_chess(10,3,2)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            value = app.Button_11.Value;
            if value == 1
                app.backend.put_chess(11,3,3)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)
            value = app.Button_12.Value;
            if value == 1
                app.backend.put_chess(12,3,4)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_13
        function Button_13ValueChanged(app, event)
            value = app.Button_13.Value;
            if value == 1
                app.backend.put_chess(13,4,1)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_14
        function Button_14ValueChanged(app, event)
            value = app.Button_14.Value;
            if value == 1
                app.backend.put_chess(14,4,2)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_15
        function Button_15ValueChanged(app, event)
            value = app.Button_15.Value;
            if value == 1
                app.backend.put_chess(15,4,3)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_16
        function Button_16ValueChanged(app, event)
            value = app.Button_16.Value;
            if value == 1
                app.backend.put_chess(16,4,4)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_17
        function Button_17ValueChanged(app, event)
            value = app.Button_17.Value;
            if value == 1
                app.backend.put_chess(17,5,1)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_18
        function Button_18ValueChanged(app, event)
            value = app.Button_18.Value;
            if value == 1
                app.backend.put_chess(18,5,2)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_19
        function Button_19ValueChanged(app, event)
            value = app.Button_19.Value;
            if value == 1
                app.backend.put_chess(19,5,3)
                app.operating_button = 0;
            end 
        end

        % Value changed function: Button_20
        function Button_20ValueChanged(app, event)
            value = app.Button_20.Value;
            if value == 1
                app.backend.put_chess(20,5,4)
                app.operating_button = 0;
            end 
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            for i = 1:20
                app.backend.release_func(i)
            end
            app.init_define(app.chessboard,app.backend)

        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            %we should init all the button
            if(app.B1_put && app.B2_put && app.B3_put &&app.B4_put&& app.B5_put && app.B6_put&& app.B7_put &&app.B8_put &&app.B9_put&&app.B10_put )
                ui = app_play();
                step_controller = step(ui);
                history_chessboard = history();
                ui.init_history(history_chessboard)
                ui.Stepconstructer(step_controller,0)
                app.chessboard.init_ui(ui)
                ui.init_chessboard(app.chessboard)
                delete(app)            
            else
                app.Label.Text = '未完成所有按钮的初始化';
           end
            
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            ui = app_menu();
            matrix = [3 1 1 4 ; 3 1 1 4;5 2 2 6;5 8 9 6;7 0 0 10];
            app.chessboard.init_board(matrix)
            ui.init(app.chessboard)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Chess_Board = uibuttongroup(app.UIFigure);
            app.GridLayout = uigridlayout(app.Chess_Board);
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.GridLayout, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.GridLayout, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.GridLayout, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @Button_13ValueChanged, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.GridLayout, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @Button_14ValueChanged, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'state');
            app.Button_15.ValueChangedFcn = createCallbackFcn(app, @Button_15ValueChanged, true);
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.GridLayout, 'state');
            app.Button_16.ValueChangedFcn = createCallbackFcn(app, @Button_16ValueChanged, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout, 'state');
            app.Button_17.ValueChangedFcn = createCallbackFcn(app, @Button_17ValueChanged, true);
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.GridLayout, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_18ValueChanged, true);
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.GridLayout, 'state');
            app.Button_19.ValueChangedFcn = createCallbackFcn(app, @Button_19ValueChanged, true);
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'state');
            app.Button_20.ValueChangedFcn = createCallbackFcn(app, @Button_20ValueChanged, true);
            app.Button_20.Text = '';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = '游玩';
            app.B10 = uibutton(app.UIFigure, 'state');
            app.B10.ValueChangedFcn = createCallbackFcn(app, @B10ValueChanged, true);
            app.B10.Icon = '007.jpeg';
            app.B10.Text = '';
            app.B9 = uibutton(app.UIFigure, 'state');
            app.B9.ValueChangedFcn = createCallbackFcn(app, @B9ValueChanged, true);
            app.B9.Icon = '007.jpeg';
            app.B9.Text = '';
            app.B8 = uibutton(app.UIFigure, 'state');
            app.B8.ValueChangedFcn = createCallbackFcn(app, @B8ValueChanged, true);
            app.B8.Icon = '007.jpeg';
            app.B8.Text = '';
            app.B7 = uibutton(app.UIFigure, 'state');
            app.B7.ValueChangedFcn = createCallbackFcn(app, @B7ValueChanged, true);
            app.B7.Icon = '007.jpeg';
            app.B7.Text = '';
            app.B6 = uibutton(app.UIFigure, 'state');
            app.B6.ValueChangedFcn = createCallbackFcn(app, @B6ValueChanged, true);
            app.B6.Icon = '006.jpg';
            app.B6.Text = '';
            app.B5 = uibutton(app.UIFigure, 'state');
            app.B5.ValueChangedFcn = createCallbackFcn(app, @B5ValueChanged, true);
            app.B5.Icon = '005.jpeg';
            app.B5.Text = '';
            app.B4 = uibutton(app.UIFigure, 'state');
            app.B4.ValueChangedFcn = createCallbackFcn(app, @B4ValueChanged, true);
            app.B4.Icon = '004.jpeg';
            app.B4.Text = '';
            app.B3 = uibutton(app.UIFigure, 'state');
            app.B3.ValueChangedFcn = createCallbackFcn(app, @B3ValueChanged, true);
            app.B3.Icon = '003.jpeg';
            app.B3.Text = '';
            app.B2 = uibutton(app.UIFigure, 'state');
            app.B2.ValueChangedFcn = createCallbackFcn(app, @B2ValueChanged, true);
            app.B2.Icon = '002.png';
            app.B2.Text = '';
            app.B1 = uibutton(app.UIFigure, 'state');
            app.B1.ValueChangedFcn = createCallbackFcn(app, @B1ValueChanged, true);
            app.B1.Icon = '001.jpg';
            app.B1.Text = '';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '返回主菜单';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '按钮放置';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '重新放置';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_define

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end