classdef app_history < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_5   matlab.ui.control.Label
        Title_6   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Title_5   matlab.ui.control.Label
        Title_4   matlab.ui.control.Label
        Title_3   matlab.ui.control.Label
        Title_2   matlab.ui.control.Label
        Title     matlab.ui.control.Label
        Button    matlab.ui.control.Button
    end

    
    properties (Access = private)
        backend % Description
    end
    
    methods (Access = public)
        
        function  init(app,back)
            app.backend = back;
            app.backend.update()
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ui = app_menu();
            board = chessboard();
            matrix = [3 1 1 4 ; 3 1 1 4;5 2 2 6;5 8 9 6;7 0 0 10];
            board.init_board(matrix)
            ui.init(board)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '返回主菜单';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '历史记录';
            app.Title_2 = uilabel(app.UIFigure);
            app.Title_2.Text = '关卡1';
            app.Title_3 = uilabel(app.UIFigure);
            app.Title_3.Text = '关卡2';
            app.Title_4 = uilabel(app.UIFigure);
            app.Title_4.Text = '关卡3';
            app.Title_5 = uilabel(app.UIFigure);
            app.Title_5.Text = '关卡4';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '暂无记录';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '暂无记录';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '暂无记录';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '暂无记录';
            app.Title_6 = uilabel(app.UIFigure);
            app.Title_6.Text = '自定义';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '暂无记录';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_history

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end