classdef app_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        getHistory  matlab.ui.control.Button
        define      matlab.ui.control.Button
        start       matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
        Label       matlab.ui.control.Label
    end

    
    properties (Access = private)
        chessboard % Description
        level = 1
        
    end
    
    methods (Access = public)
        
        function init(app,chessboard)
            app.chessboard = chessboard;
        end
        function translate(app)
            switch app.DropDown.Value
                case '关卡1'
                    matrix = [3,4,5,0;3,4,5,6;2,2,7,6;8,0,1,1;9,10,1,1];
                    app.chessboard.init_board(matrix) 
                    app.level = 1;
                case '关卡2'
                    matrix = [2 2 8 6 ; 5 1 1 6 ;5 1 1 3;4 0 0 3;4 10 9 7];
                    app.chessboard.init_board(matrix)
                    app.level = 2;
                case '关卡3'
                    matrix = [0 1 1 0 ; 3 1 1 4 ;3 5 6 4 ;7 5 6 8;2 2 9 10];
                    app.chessboard.init_board(matrix)
                    app.level = 3;
                case '关卡4'
                    matrix = [3 1 1 4 ; 3 1 1 4 ;7 8 9 10;5 2 2 6;5 0 0 6];
                    app.chessboard.init_board(matrix)
                    app.level = 4;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            value = app.DropDown.Value;
            

        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            ui = app_play();
            step_controller = step(ui);
            history_chessboard = history();
            ui.init_history(history_chessboard)
            app.translate()
            ui.Stepconstructer(step_controller,app.level)
            app.chessboard.init_ui(ui)
            ui.init_chessboard(app.chessboard)
            
            delete(app)
        end

        % Button pushed function: define
        function defineButtonPushed(app, event)
            ui = app_define();
            back  = define_back(ui);
            ui.init_define(app.chessboard,back)
            delete(app)
        end

        % Button pushed function: getHistory
        function getHistoryPushed(app, event)
            ui = app_history();
            back  = history_backend(ui);
            ui.init(back)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '关卡选择';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'关卡1', '关卡2', '关卡3', '关卡4'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = '关卡1';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始游戏';
            app.define = uibutton(app.UIFigure, 'push');
            app.define.ButtonPushedFcn = createCallbackFcn(app, @defineButtonPushed, true);
            app.define.Text = '自定义';
            app.getHistory = uibutton(app.UIFigure, 'push');
            app.getHistory.ButtonPushedFcn = createCallbackFcn(app, @getHistoryPushed, true);
            app.getHistory.Text = '历史记录';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end