classdef app_play < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        reset        matlab.ui.control.Button
        Chess_Board  matlab.ui.container.ButtonGroup
        GridLayout   matlab.ui.container.GridLayout
        chess1_2_0   matlab.ui.control.Button
        chess1_1_3   matlab.ui.control.Button
        chess1_1_2   matlab.ui.control.Button
        chess1_1_1   matlab.ui.control.Button
        chess1_1_0   matlab.ui.control.Button
        chess1_2_3   matlab.ui.control.Button
        chess1_2_2   matlab.ui.control.Button
        chess1_2_1   matlab.ui.control.Button
        chess2_1     matlab.ui.control.Button
        chess2_2     matlab.ui.control.Button
        left_move    matlab.ui.control.Button
        right_move   matlab.ui.control.Button
        down_move    matlab.ui.control.Button
        up_move      matlab.ui.control.Button
        score_board  matlab.ui.control.Label
        back         matlab.ui.control.Button
        menu         matlab.ui.control.Button
    end

    
    properties (Access = public)
        % The counter of steps
        Stepcounter 
        % The list that record the chessboard(In forms of n*4*5 matrix)
        % The first row is the stepcounter[record chessboard in each step]
        history_chessboard
        %1*1 button we have 4
        move_1_1_0
        move_1_1_1
        move_1_1_2
        move_1_1_3
        %1*2 button we have 4
        move_1_2_0 
        move_1_2_1
        move_1_2_2
        move_1_2_3
        %2*1 button we have 1
        move2_1 
        %2*2 button we have 1
        move2_2    
        %The chessboard
        board
        operating_chess_number
    end
    
    properties (Access = private)
        % about the logic of button's movement

        level
        %operating chess
        operating_chess
        
        operation_movement
    end
    
    methods (Access = public)
        %We need to do initialization here
        function Stepconstructer(app,constructer,number)
            app.Stepcounter = constructer;
            app.level = number;
        end
        
        function init_history(app,history)
            app.history_chessboard = history;
        end

        function init_chessboard(app,chessboard)
            app.board = chessboard;
            
            app.history_chessboard.matrix_replace(app.board.get_chessboard(),1)
            %chess size 2*2 init
            app.move2_2 = move_2_2();
            %chess size 2*1 init
            app.move2_1 = move_2_1();
            %chess size 1*2 init
            app.move_1_2_0 = move_1_2();
            app.move_1_2_1 = move_1_2();
            app.move_1_2_2 = move_1_2();
            app.move_1_2_3 = move_1_2();
            %chess size 1*1 init
            app.move_1_1_0 = move_1_1();
            app.move_1_1_1 = move_1_1();
            app.move_1_1_2 = move_1_1();
            app.move_1_1_3 = move_1_1();

            app.board.chess_init();
            app.check_win();
        end

    end
    methods(Access = private)
        function stepIncrease(app)
            app.Stepcounter.increment();
            
        end
        
        function stepDecreadse(app)
            app.Stepcounter.decrement();
        end

        function stepset(app,value)
            app.Stepcounter.set(value);            
        end
        function  chosen_chess(app,button,number,movement)
            app.operating_chess = button;
            app.operating_chess_number = number;
            app.operation_movement = movement;
        end
        
        function check_win(app)
            if app.board.check_win()
                app.score_board.Text = 'Win!';
                app.Stepcounter.data_record(app.level);
            end
        end

        function result = left_movement_check(app)
            result = app.operation_movement.left_check(app.board.get_chessboard);
        end
        function result = right_movement_check(app)
            result = app.operation_movement.right_check(app.board.get_chessboard);
        end
        function result = up_movement_check(app)
            result = app.operation_movement.up_check(app.board.get_chessboard);
        end
        function result = down_movement_check(app)
            result = app.operation_movement.down_check(app.board.get_chessboard);
        end
        
        function leftmove_operation(app)
            app.board.left_update(app.operating_chess_number,app.operation_movement.get_row(),app.operation_movement.get_column())
            app.operation_movement.left_move()
            app.operating_chess.Layout.Column = app.operating_chess.Layout.Column - 1;
        end
        
        function rightmove_operation(app)
            app.board.right_update(app.operating_chess_number,app.operation_movement.get_row(),app.operation_movement.get_column())
            app.operation_movement.right_move()
            app.operating_chess.Layout.Column = app.operating_chess.Layout.Column + 1;        
        end
        function upmove_operation(app)
            app.board.up_update(app.operating_chess_number,app.operation_movement.get_row(),app.operation_movement.get_column())
            app.operation_movement.up_move()
            app.operating_chess.Layout.Row = app.operating_chess.Layout.Row - 1;
        end
        function downmove_operation(app)
            app.board.down_update(app.operating_chess_number,app.operation_movement.get_row(),app.operation_movement.get_column())
            app.operation_movement.down_move()
            app.operating_chess.Layout.Row = app.operating_chess.Layout.Row + 1;            
        end

       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chess2_2
        function chess2_2_chosen(app, event)
            
            %This is a 2*2 button 
            app.chosen_chess(app.chess2_2,1,app.move2_2);
        end

        % Button pushed function: chess2_1
        function chess2_1_chosen(app, event)
            %This is a 2*1 button
            app.chosen_chess(app.chess2_1,2,app.move2_1);
        end

        % Button pushed function: chess1_2_0
        function chess1_2_0_chosen(app, event)
            %This is a 1*2 button
            app.chosen_chess(app.chess1_2_0,3,app.move_1_2_0);
        end

        % Button pushed function: chess1_2_1
        function chess1_2_1_chosen(app, event)
            %This is a 1*2 button
            app.chosen_chess(app.chess1_2_1,4,app.move_1_2_1);
        end

        % Button pushed function: chess1_2_2
        function chess1_2_2_chosen(app, event)
            %This is a 1*2 button
            app.chosen_chess(app.chess1_2_2,5,app.move_1_2_2);
        end

        % Button pushed function: chess1_2_3
        function chess1_2_3_chosen(app, event)
            %This is a 1*2 button
            app.chosen_chess(app.chess1_2_3,6,app.move_1_2_3);
        end

        % Button pushed function: chess1_1_0
        function chess1_1_0_chosen(app, event)
            %This is a 1*1 button
            app.chosen_chess(app.chess1_1_0,7,app.move_1_1_0);
        end

        % Button pushed function: chess1_1_1
        function chess1_1_1_chosen(app, event)
            %This is a 1*1 button
            app.chosen_chess(app.chess1_1_1,8,app.move_1_1_1);
        end

        % Button pushed function: chess1_1_2
        function chess1_1_2_chosen(app, event)
            %This is a 1*1 button
            app.chosen_chess(app.chess1_1_2,9,app.move_1_1_2);            
        end

        % Button pushed function: chess1_1_3
        function chess1_1_3_chosen(app, event)
            %This is a 1*1 button
            app.chosen_chess(app.chess1_1_3,10,app.move_1_1_3);
        end

        % Button pushed function: left_move
        function left_moveButtonPushed(app, event)
            if app.left_movement_check()
                app.leftmove_operation()
                app.stepIncrease()
                app.check_win()
                app.history_chessboard.matrix_replace(app.board.get_chessboard,app.Stepcounter.value()+1)
            end
        end

        % Button pushed function: right_move
        function right_moveButtonPushed(app, event)
            if app.right_movement_check()
                app.rightmove_operation()
                app.stepIncrease()
                app.check_win();
                app.history_chessboard.matrix_replace(app.board.get_chessboard,app.Stepcounter.value()+1)
            end
        end

        % Button pushed function: down_move
        function down_moveButtonPushed(app, event)
            if app.down_movement_check() 
                app.downmove_operation()
                app.stepIncrease()
                app.check_win()  
                app.history_chessboard.matrix_replace(app.board.get_chessboard,app.Stepcounter.value()+1)
            end    
        end

        % Button pushed function: up_move
        function up_moveButtonPushed(app, event)
            if app.up_movement_check()
                app.upmove_operation()
                app.stepIncrease()
                app.check_win();
                app.history_chessboard.matrix_replace(app.board.get_chessboard,app.Stepcounter.value()+1)
            end    
        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            app.stepDecreadse()
            app.board.init_board(app.history_chessboard.read_matrix(app.Stepcounter.value()+1));
            app.board.chess_init();
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            app.stepset(0)
            app.board.init_board(app.history_chessboard.read_matrix(app.Stepcounter.value()+1));
            app.board.chess_init();           
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value  < app.Stepcounter.value()
                app.stepset(value)
                app.board.init_board(app.history_chessboard.read_matrix(app.Stepcounter.value()+1));
                app.board.chess_init(); 
            end
        end

        % Button pushed function: menu
        function menuButtonPushed(app, event)
            ui = app_menu();
            matrix = [3 1 1 4 ; 3 1 1 4;5 2 2 6;5 8 9 6;7 0 0 10];
            app.board.init_board(matrix)
            ui.init(app.board)
            delete(app)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.level < 4)
                app.stepset(0)
                app.level = app.level +1;
                app.board.nextlevel(app.level)
                app.history_chessboard.matrix_replace(app.board.get_chessboard(),1)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            list = app.board.get_information();
            ui = help();
            ui.init(list,app.board.get_chessboard());
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menu = uibutton(app.UIFigure, 'push');
            app.menu.ButtonPushedFcn = createCallbackFcn(app, @menuButtonPushed, true);
            app.menu.Text = '返回主菜单';
            app.back = uibutton(app.UIFigure, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = '撤回';
            app.score_board = uilabel(app.UIFigure);
            app.score_board.Text = '0';
            app.up_move = uibutton(app.UIFigure, 'push');
            app.up_move.ButtonPushedFcn = createCallbackFcn(app, @up_moveButtonPushed, true);
            app.up_move.Text = '▲';
            app.down_move = uibutton(app.UIFigure, 'push');
            app.down_move.ButtonPushedFcn = createCallbackFcn(app, @down_moveButtonPushed, true);
            app.down_move.Text = '▼';
            app.right_move = uibutton(app.UIFigure, 'push');
            app.right_move.ButtonPushedFcn = createCallbackFcn(app, @right_moveButtonPushed, true);
            app.right_move.Text = '▶';
            app.left_move = uibutton(app.UIFigure, 'push');
            app.left_move.ButtonPushedFcn = createCallbackFcn(app, @left_moveButtonPushed, true);
            app.left_move.Text = '◀';
            app.Chess_Board = uibuttongroup(app.UIFigure);
            app.GridLayout = uigridlayout(app.Chess_Board);
            app.chess2_2 = uibutton(app.GridLayout, 'push');
            app.chess2_2.ButtonPushedFcn = createCallbackFcn(app, @chess2_2_chosen, true);
            app.chess2_2.Icon = '001.jpg';
            app.chess2_2.Text = '';
            app.chess2_1 = uibutton(app.GridLayout, 'push');
            app.chess2_1.ButtonPushedFcn = createCallbackFcn(app, @chess2_1_chosen, true);
            app.chess2_1.Icon = '002.png';
            app.chess2_1.Text = '';
            app.chess1_2_1 = uibutton(app.GridLayout, 'push');
            app.chess1_2_1.ButtonPushedFcn = createCallbackFcn(app, @chess1_2_1_chosen, true);
            app.chess1_2_1.Icon = '004.jpeg';
            app.chess1_2_1.Text = '';
            app.chess1_2_2 = uibutton(app.GridLayout, 'push');
            app.chess1_2_2.ButtonPushedFcn = createCallbackFcn(app, @chess1_2_2_chosen, true);
            app.chess1_2_2.Icon = '005.jpeg';
            app.chess1_2_2.Text = '';
            app.chess1_2_3 = uibutton(app.GridLayout, 'push');
            app.chess1_2_3.ButtonPushedFcn = createCallbackFcn(app, @chess1_2_3_chosen, true);
            app.chess1_2_3.Icon = '006.jpg';
            app.chess1_2_3.Text = '';
            app.chess1_1_0 = uibutton(app.GridLayout, 'push');
            app.chess1_1_0.ButtonPushedFcn = createCallbackFcn(app, @chess1_1_0_chosen, true);
            app.chess1_1_0.Icon = '007.jpeg';
            app.chess1_1_0.Text = '';
            app.chess1_1_1 = uibutton(app.GridLayout, 'push');
            app.chess1_1_1.ButtonPushedFcn = createCallbackFcn(app, @chess1_1_1_chosen, true);
            app.chess1_1_1.Icon = '007.jpeg';
            app.chess1_1_1.Text = '';
            app.chess1_1_2 = uibutton(app.GridLayout, 'push');
            app.chess1_1_2.ButtonPushedFcn = createCallbackFcn(app, @chess1_1_2_chosen, true);
            app.chess1_1_2.Icon = '007.jpeg';
            app.chess1_1_2.Text = '';
            app.chess1_1_3 = uibutton(app.GridLayout, 'push');
            app.chess1_1_3.ButtonPushedFcn = createCallbackFcn(app, @chess1_1_3_chosen, true);
            app.chess1_1_3.Icon = '007.jpeg';
            app.chess1_1_3.Text = '';
            app.chess1_2_0 = uibutton(app.GridLayout, 'push');
            app.chess1_2_0.ButtonPushedFcn = createCallbackFcn(app, @chess1_2_0_chosen, true);
            app.chess1_2_0.Icon = '003.jpeg';
            app.chess1_2_0.Text = '';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = '重置';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '下一关';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '帮助';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_play

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end