classdef help < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_11  matlab.ui.control.Label
        Label_10  matlab.ui.control.Label
        Label_9   matlab.ui.control.Label
        Label_8   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
        Label_6   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.Button
    end


    methods (Access = public)
        
        function init(app,list,matrix)
            app.Label.Text = ['Chess1 = Chess2_2Template(' ,num2str(list(5)), ',', num2str(list(6)) , ',1);'];
            app.Label_2.Text = ['Chess2 = Chess2_1Template(' , num2str(list(7)) , ',' , num2str(list(8)) , ',2);'];
            app.Label_3.Text = ['Chess3 = Chess1_2Template(' , num2str(list(9)) , ',' , num2str(list(10)) , ',3);'];
            app.Label_4.Text = ['Chess4 = Chess1_2Template(' , num2str(list(11)) , ',' , num2str(list(12)) , ',4);'];
            app.Label_5.Text = ['Chess5 = Chess1_2Template(' , num2str(list(13)) , ',' , num2str(list(14)) , ',5);'];
            app.Label_6.Text = ['Chess6 = Chess1_2Template(' , num2str(list(15)) , ',' , num2str(list(16)) , ',6);'];
            app.Label_7.Text = ['Chess7 = Chess1_1Template(' , num2str(list(17)) , ',' , num2str(list(18)) , ',7);'];
            app.Label_8.Text = ['Chess8 = Chess1_1Template(' , num2str(list(19)) , ',' , num2str(list(20)) , ',8);'];
            app.Label_9.Text = ['Chess9 = Chess1_1Template(' , num2str(list(21)) , ',' , num2str(list(22)) , ',9);'];
            app.Label_10.Text = ['Chess10 = Chess1_1Template(' , num2str(list(23)) , ',' , num2str(list(24)) , ',10);'];
            
            app.Label_11.Text = ['int chess[5][4] = ' ,'{' ,'{',num2str(matrix(1,1)),',',num2str(matrix(1,2)),',',num2str(matrix(1,3)),',',num2str(matrix(1,4)),'}',',','{',num2str(matrix(2,1)),',',num2str(matrix(2,2)),',',num2str(matrix(2,3)),',',num2str(matrix(2,4)),'}',',','{',num2str(matrix(3,1)),',',num2str(matrix(3,2)),',',num2str(matrix(3,3)),',',num2str(matrix(3,4)),'}',',','{',num2str(matrix(4,1)),',',num2str(matrix(4,2)),',',num2str(matrix(4,3)),',',num2str(matrix(4,4)),'}',',','{',num2str(matrix(5,1)),',',num2str(matrix(5,2)),',',num2str(matrix(5,3)),',',num2str(matrix(5,4)),'}','}'];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '关闭';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = help

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end