classdef view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CounterUIFigure         matlab.ui.Figure
        FrontPage               matlab.ui.container.Panel
        Label_80                matlab.ui.control.Label
        EditField_2             matlab.ui.control.Label
        EditField               matlab.ui.control.Label
        sort_way                matlab.ui.control.DropDown
        Label_24                matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        frontPageFrontPage      matlab.ui.control.Button
        DatePicker              matlab.ui.control.DatePicker
        Label_3                 matlab.ui.control.Label
        DropDown_2              matlab.ui.control.DropDown
        DropDown_2Label         matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        frontPageMine           matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        payPanel                matlab.ui.container.Panel
        LeapTimePanel           matlab.ui.container.Panel
        Label_83                matlab.ui.control.Label
        Button_15               matlab.ui.control.Button
        payTimeLeft             matlab.ui.control.Label
        Label_82                matlab.ui.control.Label
        Label_81                matlab.ui.control.Label
        payWhetherTransfer      matlab.ui.control.Label
        payArrivalTime          matlab.ui.control.Label
        payArrivalStation       matlab.ui.control.Label
        payDepartureTime        matlab.ui.control.Label
        payDepartureStation     matlab.ui.control.Label
        payPrice                matlab.ui.control.Label
        buyChooseSeat           matlab.ui.control.DropDown
        Label_29                matlab.ui.control.Label
        buyWhetherTransfer      matlab.ui.control.Label
        buyArrivalTime          matlab.ui.control.Label
        buyDepartureTime        matlab.ui.control.Label
        buyArrivalStation       matlab.ui.control.Label
        buyDepartureStation     matlab.ui.control.Label
        buyAssure               matlab.ui.control.Button
        buyBackToHome           matlab.ui.control.Button
        buyTicket               matlab.ui.control.Label
        buyPrice                matlab.ui.control.Label
        searchPanel             matlab.ui.container.Panel
        secondclass_4           matlab.ui.control.Label
        firstclass_4            matlab.ui.control.Label
        Label_42                matlab.ui.control.Label
        Label_41                matlab.ui.control.Label
        secondclass_3           matlab.ui.control.Label
        firstclass_3            matlab.ui.control.Label
        Label_40                matlab.ui.control.Label
        Label_39                matlab.ui.control.Label
        secondclass_2           matlab.ui.control.Label
        firstclass_2            matlab.ui.control.Label
        Label_38                matlab.ui.control.Label
        Label_37                matlab.ui.control.Label
        secondclass_1           matlab.ui.control.Label
        firstclass_1            matlab.ui.control.Label
        Label_31                matlab.ui.control.Label
        Label_30                matlab.ui.control.Label
        Buy_Button_4            matlab.ui.control.Button
        Buy_Button_3            matlab.ui.control.Button
        Buy_Button_2            matlab.ui.control.Button
        Buy_Button_1            matlab.ui.control.Button
        Panel_7                 matlab.ui.container.Panel
        train2_4                matlab.ui.control.Label
        train1_4                matlab.ui.control.Label
        price_4                 matlab.ui.control.Label
        Label_28                matlab.ui.control.Label
        interval_time_4         matlab.ui.control.Label
        Label_23                matlab.ui.control.Label
        transfer_station_4      matlab.ui.control.Label
        Label_22                matlab.ui.control.Label
        Label_21                matlab.ui.control.Label
        Label_20                matlab.ui.control.Label
        arrival_time_4          matlab.ui.control.Label
        departure_time_4        matlab.ui.control.Label
        arrival_station_4       matlab.ui.control.Label
        departure_station_4     matlab.ui.control.Label
        Panel_6                 matlab.ui.container.Panel
        train2_3                matlab.ui.control.Label
        train1_3                matlab.ui.control.Label
        price_3                 matlab.ui.control.Label
        Label_27                matlab.ui.control.Label
        interval_time_3         matlab.ui.control.Label
        Label_19                matlab.ui.control.Label
        transfer_station_3      matlab.ui.control.Label
        Label_18                matlab.ui.control.Label
        Label_17                matlab.ui.control.Label
        Label_16                matlab.ui.control.Label
        arrival_time_3          matlab.ui.control.Label
        departure_time_3        matlab.ui.control.Label
        arrival_station_3       matlab.ui.control.Label
        departure_station_3     matlab.ui.control.Label
        Panel_5                 matlab.ui.container.Panel
        train2_2                matlab.ui.control.Label
        train1_2                matlab.ui.control.Label
        price_2                 matlab.ui.control.Label
        Label_26                matlab.ui.control.Label
        interval_time_2         matlab.ui.control.Label
        Label_15                matlab.ui.control.Label
        transfer_station_2      matlab.ui.control.Label
        Label_14                matlab.ui.control.Label
        Label_13                matlab.ui.control.Label
        Label_12                matlab.ui.control.Label
        arrival_time_2          matlab.ui.control.Label
        departure_time_2        matlab.ui.control.Label
        arrival_station_2       matlab.ui.control.Label
        departure_station_2     matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        train2                  matlab.ui.control.Label
        train1                  matlab.ui.control.Label
        price                   matlab.ui.control.Label
        Label_25                matlab.ui.control.Label
        interval_time           matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        transfer_station        matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        arrival_time            matlab.ui.control.Label
        departure_time          matlab.ui.control.Label
        arrival_station         matlab.ui.control.Label
        departure_station       matlab.ui.control.Label
        Button_6                matlab.ui.control.Button
        TicketPanel             matlab.ui.container.Panel
        boardButton             matlab.ui.control.StateButton
        RescheduleButton        matlab.ui.control.Button
        reassure_cancel_ticket  matlab.ui.container.Panel
        cancelTicket            matlab.ui.control.Button
        cancelCancelTicket      matlab.ui.control.Button
        Label_33                matlab.ui.control.Label
        payWhetherTransfer_2    matlab.ui.control.Label
        paySeatQuality_2        matlab.ui.control.Label
        buyWhetherTransfer_2    matlab.ui.control.Label
        buySeatQuality_2        matlab.ui.control.Label
        payArrivalTime_2        matlab.ui.control.Label
        payArrivalStation_2     matlab.ui.control.Label
        payDepartureTime_2      matlab.ui.control.Label
        payDepartureStation_2   matlab.ui.control.Label
        payPrice_2              matlab.ui.control.Label
        buyArrivalTime_2        matlab.ui.control.Label
        buyDepartureTime_2      matlab.ui.control.Label
        buyArrivalStation_2     matlab.ui.control.Label
        buyDepartureStation_2   matlab.ui.control.Label
        buyPrice_2              matlab.ui.control.Label
        Label_35                matlab.ui.control.Label
        tryCancelTicket         matlab.ui.control.Button
        ticketBackToMine        matlab.ui.control.Button
        LabelLogInId_2          matlab.ui.control.Label
        LabelUser_2             matlab.ui.control.Label
        Label_32                matlab.ui.control.Label
        MineBeforeLogin         matlab.ui.container.Panel
        Button_9                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        login                   matlab.ui.control.Button
        accountId               matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        rescheduleFront         matlab.ui.container.Panel
        Button_re_back          matlab.ui.control.Button
        sort_way_re             matlab.ui.control.DropDown
        Label_45                matlab.ui.control.Label
        DropDown_re_to          matlab.ui.control.DropDown
        DropDown_4Label         matlab.ui.control.Label
        DropDown_re_from        matlab.ui.control.DropDown
        DropDown_3Label         matlab.ui.control.Label
        Button_re_search        matlab.ui.control.Button
        reschedulePay           matlab.ui.container.Panel
        Label_75                matlab.ui.control.Label
        payWhetherTransfer_3    matlab.ui.control.Label
        payArrivalTime_3        matlab.ui.control.Label
        payArrivalStation_3     matlab.ui.control.Label
        payDepartureTime_3      matlab.ui.control.Label
        payDepartureStation_3   matlab.ui.control.Label
        payPrice_3              matlab.ui.control.Label
        buyChooseSeat_2         matlab.ui.control.DropDown
        Label_74                matlab.ui.control.Label
        buyWhetherTransfer_3    matlab.ui.control.Label
        buyArrivalTime_3        matlab.ui.control.Label
        buyDepartureTime_3      matlab.ui.control.Label
        buyArrivalStation_3     matlab.ui.control.Label
        buyDepartureStation_3   matlab.ui.control.Label
        buyAssure_2             matlab.ui.control.Button
        buyBackToHome_2         matlab.ui.control.Button
        buyTicket_2             matlab.ui.control.Label
        buyPrice_3              matlab.ui.control.Label
        SuccessPanel            matlab.ui.container.Panel
        successButton           matlab.ui.control.Button
        successLabel            matlab.ui.control.Label
        rescheduleSearch        matlab.ui.container.Panel
        secondclass_8           matlab.ui.control.Label
        firstclass_8            matlab.ui.control.Label
        Label_53                matlab.ui.control.Label
        Label_52                matlab.ui.control.Label
        secondclass_7           matlab.ui.control.Label
        firstclass_7            matlab.ui.control.Label
        Label_51                matlab.ui.control.Label
        Label_50                matlab.ui.control.Label
        secondclass_6           matlab.ui.control.Label
        firstclass_6            matlab.ui.control.Label
        Label_49                matlab.ui.control.Label
        Label_48                matlab.ui.control.Label
        secondclass_5           matlab.ui.control.Label
        firstclass_5            matlab.ui.control.Label
        Label_47                matlab.ui.control.Label
        Label_46                matlab.ui.control.Label
        Buy_Button_8            matlab.ui.control.Button
        Buy_Button_7            matlab.ui.control.Button
        Buy_Button_6            matlab.ui.control.Button
        Buy_Button_5            matlab.ui.control.Button
        Panel_11                matlab.ui.container.Panel
        train2_8                matlab.ui.control.Label
        train1_8                matlab.ui.control.Label
        price_8                 matlab.ui.control.Label
        Label_73                matlab.ui.control.Label
        interval_time_8         matlab.ui.control.Label
        Label_72                matlab.ui.control.Label
        transfer_station_8      matlab.ui.control.Label
        Label_71                matlab.ui.control.Label
        Label_70                matlab.ui.control.Label
        Label_69                matlab.ui.control.Label
        arrival_time_8          matlab.ui.control.Label
        departure_time_8        matlab.ui.control.Label
        arrival_station_8       matlab.ui.control.Label
        departure_station_8     matlab.ui.control.Label
        Panel_10                matlab.ui.container.Panel
        train2_7                matlab.ui.control.Label
        train1_7                matlab.ui.control.Label
        price_7                 matlab.ui.control.Label
        Label_68                matlab.ui.control.Label
        interval_time_7         matlab.ui.control.Label
        Label_67                matlab.ui.control.Label
        transfer_station_7      matlab.ui.control.Label
        Label_66                matlab.ui.control.Label
        Label_65                matlab.ui.control.Label
        Label_64                matlab.ui.control.Label
        arrival_time_7          matlab.ui.control.Label
        departure_time_7        matlab.ui.control.Label
        arrival_station_7       matlab.ui.control.Label
        departure_station_7     matlab.ui.control.Label
        Panel_9                 matlab.ui.container.Panel
        train2_6                matlab.ui.control.Label
        train1_6                matlab.ui.control.Label
        price_6                 matlab.ui.control.Label
        Label_63                matlab.ui.control.Label
        interval_time_6         matlab.ui.control.Label
        Label_62                matlab.ui.control.Label
        transfer_station_6      matlab.ui.control.Label
        Label_61                matlab.ui.control.Label
        Label_60                matlab.ui.control.Label
        Label_59                matlab.ui.control.Label
        arrival_time_6          matlab.ui.control.Label
        departure_time_6        matlab.ui.control.Label
        arrival_station_6       matlab.ui.control.Label
        departure_station_6     matlab.ui.control.Label
        Panel_8                 matlab.ui.container.Panel
        train2_5                matlab.ui.control.Label
        train1_5                matlab.ui.control.Label
        price_5                 matlab.ui.control.Label
        Label_58                matlab.ui.control.Label
        interval_time_5         matlab.ui.control.Label
        Label_57                matlab.ui.control.Label
        transfer_station_5      matlab.ui.control.Label
        Label_56                matlab.ui.control.Label
        Label_55                matlab.ui.control.Label
        Label_54                matlab.ui.control.Label
        arrival_time_5          matlab.ui.control.Label
        departure_time_5        matlab.ui.control.Label
        arrival_station_5       matlab.ui.control.Label
        departure_station_5     matlab.ui.control.Label
        Button_14               matlab.ui.control.Button
        MineAfterLogin          matlab.ui.container.Panel
        NoTicketPanel           matlab.ui.container.Panel
        Label_43                matlab.ui.control.Label
        Button_13               matlab.ui.control.Button
        MyTicket                matlab.ui.control.Button
        LabelLogInId            matlab.ui.control.Label
        LabelUser               matlab.ui.control.Label
        logout                  matlab.ui.control.Button
        Label_7                 matlab.ui.control.Label
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Hang_Hu_Label           matlab.ui.control.Label
        Hu_Li_Label             matlab.ui.control.Label
        Nan_Li_Label            matlab.ui.control.Label
        Chang_Nan_Label         matlab.ui.control.Label
        Hang_Jia_Label          matlab.ui.control.Label
        Jia_Shang_Label         matlab.ui.control.Label
        Shang_Su_Label          matlab.ui.control.Label
        Su_Chang_Label          matlab.ui.control.Label
        Shang_Jia_Label         matlab.ui.control.Label
        Su_Shang_Label          matlab.ui.control.Label
        Chang_Su_Label          matlab.ui.control.Label
        Nan_Chang_Label         matlab.ui.control.Label
        Li_Nan_Label            matlab.ui.control.Label
        Li_Hu_Label             matlab.ui.control.Label
        Hu_Hang_Label           matlab.ui.control.Label
        Jia_Hang_Label          matlab.ui.control.Label
        Jia_Hang                matlab.ui.control.Lamp
        Shang_Jia               matlab.ui.control.Lamp
        Su_Shang                matlab.ui.control.Lamp
        Hu_Hang                 matlab.ui.control.Lamp
        Chang_Su                matlab.ui.control.Lamp
        Li_Hu                   matlab.ui.control.Lamp
        Nan_Chang               matlab.ui.control.Lamp
        Li_Nan                  matlab.ui.control.Lamp
        LiYangLabel             matlab.ui.control.Label
        HuZhouLabel             matlab.ui.control.Label
        HangZhouDongLabel       matlab.ui.control.Label
        JiaXingNanLabel         matlab.ui.control.Label
        ShangHaiLabel           matlab.ui.control.Label
        SuZhouBeiLabel          matlab.ui.control.Label
        ChangZhouBeiLabel       matlab.ui.control.Label
        NanJingNanLabel         matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        LampLiYang              matlab.ui.control.Lamp
        LampHuZhou              matlab.ui.control.Lamp
        LampHangZhou            matlab.ui.control.Lamp
        LampJiaXing             matlab.ui.control.Lamp
        LampShangHai            matlab.ui.control.Lamp
        LampSuZhou              matlab.ui.control.Lamp
        LampChangZhou           matlab.ui.control.Lamp
        LampNangJing            matlab.ui.control.Lamp
        Image                   matlab.ui.control.Image
        TimeControllerPanel     matlab.ui.container.Panel
        Label_79                matlab.ui.control.Label
        Label_76                matlab.ui.control.Label
        showTimeMin             matlab.ui.control.Label
        showTimeHou             matlab.ui.control.Label
        setTimeSpeed            matlab.ui.control.Button
        Label_36                matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        timeStop                matlab.ui.control.Button
        timeContinue            matlab.ui.control.Button
    end


    properties (Access = private)
        % @property {counter} controller: Controller for the counter
        dataBase 
        consult
        clock
        ticket
        account
    end    
    
    properties (Access = public)
        price_gap
        t
    end
    
    methods (Access = public)
        
        % @contructor
        % @param {counter} controller: Controller for the counter

        function constructor(app, database, consult, clock, ticket, account)
            app.dataBase = database;
            app.consult = consult;
            app.clock = clock;
            app.ticket = ticket;
            app.account = account;

            app.FrontPage.Visible = true;
            app.searchPanel.Visible = false;
            app.MineBeforeLogin.Visible = false;
            app.MineAfterLogin.Visible = false;
            app.payPanel.Visible = false;
            app.TicketPanel.Visible = false;
            app.reassure_cancel_ticket.Visible = false;
            app.TimeControllerPanel.Visible = true;
            app.NoTicketPanel.Visible = false;
            app.rescheduleFront.Visible = false;
            app.reschedulePay.Visible = false;
            app.rescheduleSearch.Visible = false;
            app.SuccessPanel.Visible = false;
            app.LeapTimePanel.Visible = false;
            
            app.DatePicker.Value = datetime('today');
            app.boardButton.Value = 1;

            
        end
        
        function results = getTime(app)
            results = app.EditField.Value * 60 + app.EditField_2.Value;
        end

        
        function setTime(app, t)
            app.EditField.Text = num2str(floor(t/60), '%02d');
            app.EditField_2.Text = num2str(mod(t, 60), '%02d');
            app.showTimeHou.Text = num2str(floor(t/60), '%02d');
            app.showTimeMin.Text = num2str(mod(t, 60), '%02d');
        end
        
        function dateAdd(app)
            app.DatePicker.Value = app.DatePicker.Value + 1;
        end
        
        function outputArg = convertSeatQuality(~, input)
            if strcmp(input, '一等座')
                outputArg = 1;
            elseif strcmp(input, '二等座')
                outputArg = 2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.FrontPage.Visible = false;
            app.searchPanel.Visible = true;

            val = app.consult.search(app.clock.time, app.DropDown.Value, app.DropDown_2.Value, app.DatePicker.Value);
            ret = app.consult.sorting(app.sort_way.Value, val);
            
            app.firstclass_1.Text = num2str(app.account.ticketLeft(ret(1), 1));
            app.secondclass_1.Text = num2str(app.account.ticketLeft(ret(1), 2));
            app.firstclass_2.Text = num2str(app.account.ticketLeft(ret(2), 1));
            app.secondclass_2.Text = num2str(app.account.ticketLeft(ret(2), 2));
            app.firstclass_3.Text = num2str(app.account.ticketLeft(ret(3), 1));
            app.secondclass_3.Text = num2str(app.account.ticketLeft(ret(3), 2));
            app.firstclass_4.Text = num2str(app.account.ticketLeft(ret(4), 1));
            app.secondclass_4.Text = num2str(app.account.ticketLeft(ret(4), 2));


            app.departure_station.Text = num2str(ret(1).departure_station);
            app.arrival_station.Text = num2str(ret(1).arrival_station);
            app.departure_time.Text = [num2str(floor(ret(1).departure_time/60), '%02d'),':',num2str(mod(ret(1).departure_time,60), '%02d')];
            app.interval_time.Text = [num2str(floor(ret(1).interval_time/60), '%02d'),'H',num2str(mod(ret(1).interval_time,60), '%02d'),'M'];
            app.arrival_time.Text = [num2str(floor(ret(1).arrival_time/60), '%02d'),':',num2str(mod(ret(1).arrival_time,60), '%02d')];
            app.transfer_station.Text = ret(1).transfer_station;
            app.price.Text = num2str(ret(1).price);
            app.train1.Text = ret(1).train_id1;
            app.train2.Text = ret(1).train_id2;
     
            app.departure_station_2.Text = num2str(ret(2).departure_station);
            app.arrival_station_2.Text = num2str(ret(2).arrival_station);
            app.departure_time_2.Text = [num2str(floor(ret(2).departure_time/60), '%02d'),':',num2str(mod(ret(2).departure_time,60), '%02d')];
            app.interval_time_2.Text = [num2str(floor(ret(2).interval_time/60), '%02d'),'H',num2str(mod(ret(2).interval_time,60), '%02d'),'M'];
            app.arrival_time_2.Text = [num2str(floor(ret(2).arrival_time/60), '%02d'),':',num2str(mod(ret(2).arrival_time,60), '%02d')];
            app.transfer_station_2.Text = ret(2).transfer_station;
            app.price_2.Text = num2str(ret(2).price);
            app.train1_2.Text = ret(2).train_id1;
            app.train2_2.Text = ret(2).train_id2;
            
     
            app.departure_station_3.Text = num2str(ret(3).departure_station);
            app.arrival_station_3.Text = num2str(ret(3).arrival_station);
            app.departure_time_3.Text = [num2str(floor(ret(3).departure_time/60), '%02d'),':',num2str(mod(ret(3).departure_time,60), '%02d')];
            app.interval_time_3.Text = [num2str(floor(ret(3).interval_time/60), '%02d'),'H',num2str(mod(ret(3).interval_time,60), '%02d'),'M'];
            app.arrival_time_3.Text = [num2str(floor(ret(3).arrival_time/60), '%02d'),':',num2str(mod(ret(3).arrival_time,60), '%02d')];
            app.transfer_station_3.Text = ret(3).transfer_station;
            app.price_3.Text = num2str(ret(3).price);
            app.train1_3.Text = ret(3).train_id1;
            app.train2_3.Text = ret(3).train_id2;


            app.departure_station_4.Text = num2str(ret(4).departure_station);
            app.arrival_station_4.Text = num2str(ret(4).arrival_station);
            app.departure_time_4.Text = [num2str(floor(ret(4).departure_time/60), '%02d'),':',num2str(mod(ret(4).departure_time,60), '%02d')];
            app.interval_time_4.Text = [num2str(floor(ret(4).interval_time/60), '%02d'),'H',num2str(mod(ret(4).interval_time,60), '%02d'),'M'];
            app.arrival_time_4.Text = [num2str(floor(ret(4).arrival_time/60), '%02d'),':',num2str(mod(ret(4).arrival_time,60), '%02d')];
            app.transfer_station_4.Text = ret(4).transfer_station;
            app.price_4.Text = num2str(ret(4).price);
            app.train1_4.Text = ret(4).train_id1;
            app.train2_4.Text = ret(4).train_id2;
            
        end

        % Button pushed function: frontPageMine
        function frontPageMinePushed(app, event)
            if app.account.logInAccountId == -1
                app.FrontPage.Visible = false;
                app.MineBeforeLogin.Visible = true;
            else
                app.FrontPage.Visible = false;
                app.MineAfterLogin.Visible = true;
            end
        end

        % Callback function
        function Button_5Pushed(app, event)
            app.FrontPage.Visible = true;
            app.MineBeforeLogin.Visible = false;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.FrontPage.Visible = true;
            app.searchPanel.Visible = false;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.FrontPage.Visible = true;
            app.MineBeforeLogin.Visible = false;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.MineAfterLogin.Visible = false;
            app.FrontPage.Visible = true;
        end

        % Button pushed function: login
        function loginPushed(app, event)
            app.account.login(app.accountId.Value);
            app.LabelLogInId.Text = num2str(app.account.logInAccountId);
            app.LabelLogInId_2.Text = num2str(app.account.logInAccountId);
            app.MineBeforeLogin.Visible = false;
            app.MineAfterLogin.Visible = true;
        end

        % Close request function: CounterUIFigure
        function CounterUIFigureCloseRequest(app, event)
            
            app.clock.delete_timer();
            app.ticket.saveInfo();
            %app.t.(1)(1) = app.getTime();
            %writetable(app.t, 'savetime.csv');
            delete(app);
            
        end

        % Button pushed function: logout
        function logoutPushed(app, event)
            app.account.logout();
            app.MineBeforeLogin.Visible = true;
            app.MineAfterLogin.Visible = false;
        end

        % Button pushed function: Buy_Button_1
        function Buy_Button_1Pushed(app, event)
            app.searchPanel.Visible = false;
            app.payPanel.Visible = true;
            app.consult.setIndex(1);
            ret = app.consult.lastArray;
            app.payDepartureStation.Text = num2str(ret(1).departure_station);
            app.payArrivalStation.Text = num2str(ret(1).arrival_station);
            app.payDepartureTime.Text = [num2str(floor(ret(1).departure_time/60), '%02d'),':',num2str(mod(ret(1).departure_time,60), '%02d')];
            app.payArrivalTime.Text = [num2str(floor(ret(1).arrival_time/60), '%02d'),':',num2str(mod(ret(1).arrival_time,60), '%02d')];
            app.payWhetherTransfer.Text = ret(1).transfer_station;
            app.payPrice.Text = num2str(ret(1).price);
            app.clock.pay_left_time = 15;
            app.LeapTimePanel.Visible = false;
        end

        % Button pushed function: buyBackToHome
        function buyBackToHomeButtonPushed(app, event)
            app.FrontPage.Visible = true;
            app.payPanel.Visible = false;
        end

        % Button pushed function: buyAssure
        function buyAssureButtonPushed(app, event)
            structIn = app.consult.lastArray(app.consult.index);
            input = app.buyChooseSeat.Value;
            seat_quality = app.convertSeatQuality(input);
            success = app.account.buyTicket(structIn, seat_quality);
            if app.account.logInAccountId == -1
                msgbox('Error', 'Please log in', 'error');
            end
            if success
                app.payPanel.Visible = false;
                app.SuccessPanel.Visible = true;
            end
        end

        % Button pushed function: ticketBackToMine
        function ticketBackToMineButtonPushed(app, event)
            app.TicketPanel.Visible = false;
            app.MineAfterLogin.Visible = true;
        end

        % Button pushed function: tryCancelTicket
        function tryCancelTicketPushed(app, event)
            app.reassure_cancel_ticket.Visible = true;
        end

        % Button pushed function: cancelCancelTicket
        function cancelCancelTicketButtonPushed(app, event)
            app.reassure_cancel_ticket.Visible = false;
        end

        % Button pushed function: cancelTicket
        function cancelTicketButtonPushed(app, event)
            app.reassure_cancel_ticket.Visible = false;
            success = app.account.tryCancelTicket();
            if success == 1
                app.TicketPanel.Visible = false;
                app.SuccessPanel.Visible = true;
            end
        end

        % Button pushed function: MyTicket
        function MyTicketButtonPushed(app, event)
            if app.account.consultTicket(2) == 0
                app.NoTicketPanel.Visible = true;
                return;
            end
            app.TicketPanel.Visible = true;
            app.payPrice_2.Text = num2str(app.account.consultTicket(11));
            app.payDepartureTime_2.Text = [num2str(floor(app.account.consultTicket(3)/60), '%02d'),':',num2str(mod(app.account.consultTicket(3),60), '%02d')];
            app.payDepartureStation_2.Text = app.account.consultTicket(6);
            app.payArrivalTime_2.Text = [num2str(floor(app.account.consultTicket(4)/60), '%02d'),':',num2str(mod(app.account.consultTicket(4),60), '%02d')];
            app.payArrivalStation_2.Text = app.account.consultTicket(7);
            app.paySeatQuality_2.Text = num2str(app.account.consultTicket(12));
            if app.account.consultTicket(9) == 0
                app.payWhetherTransfer_2.Text = '否';
            elseif app.account.consultTicket(9) == 1
                app.payWhetherTransfer_2.Text = '是';
            end
        end

        % Button pushed function: timeContinue
        function timeContinueButtonPushed(app, event)
            app.clock.startClock();
        end

        % Button pushed function: timeStop
        function timeStopButtonPushed(app, event)
            app.clock.pauseClock();
        end

        % Button pushed function: setTimeSpeed
        function setTimeSpeedButtonPushed(app, event)
            app.clock.setSpeed(app.EditField_3.Value);
        end

        % Button pushed function: Buy_Button_2
        function Buy_Button_2Pushed(app, event)
            app.searchPanel.Visible = false;
            app.payPanel.Visible = true;
            app.consult.setIndex(2);
            ret = app.consult.lastArray;
            app.payDepartureStation.Text = num2str(ret(2).departure_station);
            app.payArrivalStation.Text = num2str(ret(2).arrival_station);
            app.payDepartureTime.Text = [num2str(floor(ret(2).departure_time/60), '%02d'),':',num2str(mod(ret(2).departure_time,60), '%02d')];
            app.payArrivalTime.Text = [num2str(floor(ret(2).arrival_time/60), '%02d'),':',num2str(mod(ret(2).arrival_time,60), '%02d')];
            app.payWhetherTransfer.Text = ret(2).transfer_station;
            app.payPrice.Text = num2str(ret(2).price);
            app.clock.pay_left_time = 15;
            app.LeapTimePanel.Visible = false;
        end

        % Button pushed function: Buy_Button_3
        function Buy_Button_3Pushed(app, event)
            app.searchPanel.Visible = false;
            app.payPanel.Visible = true;
            app.consult.setIndex(3);
            ret = app.consult.lastArray;
            app.payDepartureStation.Text = num2str(ret(3).departure_station);
            app.payArrivalStation.Text = num2str(ret(3).arrival_station);
            app.payDepartureTime.Text = [num2str(floor(ret(3).departure_time/60), '%02d'),':',num2str(mod(ret(3).departure_time,60), '%02d')];
            app.payArrivalTime.Text = [num2str(floor(ret(3).arrival_time/60), '%02d'),':',num2str(mod(ret(3).arrival_time,60), '%02d')];
            app.payWhetherTransfer.Text = ret(3).transfer_station;
            app.payPrice.Text = num2str(ret(3).price);
            app.clock.pay_left_time = 15;
            app.LeapTimePanel.Visible = false;
        end

        % Button pushed function: Buy_Button_4
        function Buy_Button_4Pushed(app, event)
            app.searchPanel.Visible = false;
            app.payPanel.Visible = true;
            app.consult.setIndex(4);
            ret = app.consult.lastArray;
            app.payDepartureStation.Text = num2str(ret(4).departure_station);
            app.payArrivalStation.Text = num2str(ret(4).arrival_station);
            app.payDepartureTime.Text = [num2str(floor(ret(4).departure_time/60), '%02d'),':',num2str(mod(ret(4).departure_time,60), '%02d')];
            app.payArrivalTime.Text = [num2str(floor(ret(4).arrival_time/60), '%02d'),':',num2str(mod(ret(4).arrival_time,60), '%02d')];
            app.payWhetherTransfer.Text = ret(4).transfer_station;
            app.payPrice.Text = num2str(ret(4).price);
            app.clock.pay_left_time = 15;
            app.LeapTimePanel.Visible = false;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.NoTicketPanel.Visible = false;
        end

        % Button pushed function: RescheduleButton
        function RescheduleButtonPushed(app, event)
            app.price_gap = app.account.consultTicket(11);
            app.TicketPanel.Visible = false;
            app.rescheduleFront.Visible = true;
        end

        % Button pushed function: Button_re_back
        function Button_re_backPushed(app, event)
            app.rescheduleFront.Visible = false;
            app.MineAfterLogin.Visible = true;
        end

        % Button pushed function: Button_re_search
        function Button_re_searchPushed(app, event)
            app.rescheduleFront.Visible = false;
            app.rescheduleSearch.Visible = true;

            val = app.consult.search(app.clock.time, app.DropDown_re_from.Value, app.DropDown_re_to.Value, app.DatePicker.Value);
            ret = app.consult.sorting(app.sort_way_re.Value, val);
            
            app.firstclass_5.Text = num2str(app.account.ticketLeft(ret(1), 1));
            app.secondclass_5.Text = num2str(app.account.ticketLeft(ret(1), 2));
            app.firstclass_6.Text = num2str(app.account.ticketLeft(ret(2), 1));
            app.secondclass_6.Text = num2str(app.account.ticketLeft(ret(2), 2));
            app.firstclass_7.Text = num2str(app.account.ticketLeft(ret(3), 1));
            app.secondclass_7.Text = num2str(app.account.ticketLeft(ret(3), 2));
            app.firstclass_8.Text = num2str(app.account.ticketLeft(ret(4), 1));
            app.secondclass_8.Text = num2str(app.account.ticketLeft(ret(4), 2));


            app.departure_station_5.Text = num2str(ret(1).departure_station);
            app.arrival_station_5.Text = num2str(ret(1).arrival_station);
            app.departure_time_5.Text = [num2str(floor(ret(1).departure_time/60), '%02d'),':',num2str(mod(ret(1).departure_time,60), '%02d')];
            app.interval_time_5.Text = [num2str(floor(ret(1).interval_time/60), '%02d'),'H',num2str(mod(ret(1).interval_time,60), '%02d'),'M'];
            app.arrival_time_5.Text = [num2str(floor(ret(1).arrival_time/60), '%02d'),':',num2str(mod(ret(1).arrival_time,60), '%02d')];
            app.transfer_station_5.Text = ret(1).transfer_station;
            app.price_5.Text = num2str(ret(1).price);
            app.train1_5.Text = ret(1).train_id1;
            app.train2_5.Text = ret(1).train_id2;
     
            app.departure_station_6.Text = num2str(ret(2).departure_station);
            app.arrival_station_6.Text = num2str(ret(2).arrival_station);
            app.departure_time_6.Text = [num2str(floor(ret(2).departure_time/60), '%02d'),':',num2str(mod(ret(2).departure_time,60), '%02d')];
            app.interval_time_6.Text = [num2str(floor(ret(2).interval_time/60), '%02d'),'H',num2str(mod(ret(2).interval_time,60), '%02d'),'M'];
            app.arrival_time_6.Text = [num2str(floor(ret(2).arrival_time/60), '%02d'),':',num2str(mod(ret(2).arrival_time,60), '%02d')];
            app.transfer_station_6.Text = ret(2).transfer_station;
            app.price_6.Text = num2str(ret(2).price);
            app.train1_6.Text = ret(2).train_id1;
            app.train2_6.Text = ret(2).train_id2;
            
     
            app.departure_station_7.Text = num2str(ret(3).departure_station);
            app.arrival_station_7.Text = num2str(ret(3).arrival_station);
            app.departure_time_7.Text = [num2str(floor(ret(3).departure_time/60), '%02d'),':',num2str(mod(ret(3).departure_time,60), '%02d')];
            app.interval_time_7.Text = [num2str(floor(ret(3).interval_time/60), '%02d'),'H',num2str(mod(ret(3).interval_time,60), '%02d'),'M'];
            app.arrival_time_7.Text = [num2str(floor(ret(3).arrival_time/60), '%02d'),':',num2str(mod(ret(3).arrival_time,60), '%02d')];
            app.transfer_station_7.Text = ret(3).transfer_station;
            app.price_7.Text = num2str(ret(3).price);
            app.train1_7.Text = ret(3).train_id1;
            app.train2_7.Text = ret(3).train_id2;


            app.departure_station_8.Text = num2str(ret(4).departure_station);
            app.arrival_station_8.Text = num2str(ret(4).arrival_station);
            app.departure_time_8.Text = [num2str(floor(ret(4).departure_time/60), '%02d'),':',num2str(mod(ret(4).departure_time,60), '%02d')];
            app.interval_time_8.Text = [num2str(floor(ret(4).interval_time/60), '%02d'),'H',num2str(mod(ret(4).interval_time,60), '%02d'),'M'];
            app.arrival_time_8.Text = [num2str(floor(ret(4).arrival_time/60), '%02d'),':',num2str(mod(ret(4).arrival_time,60), '%02d')];
            app.transfer_station_8.Text = ret(4).transfer_station;
            app.price_8.Text = num2str(ret(4).price);
            app.train1_8.Text = ret(4).train_id1;
            app.train2_8.Text = ret(4).train_id2;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.rescheduleSearch.Visible = false;
            app.MineAfterLogin.Visible = true;
        end

        % Button pushed function: Buy_Button_5
        function Buy_Button_5Pushed(app, event)
            app.rescheduleSearch.Visible = false;
            app.reschedulePay.Visible = true;
            app.consult.setIndex(1);
            ret = app.consult.lastArray;
            app.payDepartureStation_3.Text = num2str(ret(1).departure_station);
            app.payArrivalStation_3.Text = num2str(ret(1).arrival_station);
            app.payDepartureTime_3.Text = [num2str(floor(ret(1).departure_time/60), '%02d'),':',num2str(mod(ret(1).departure_time,60), '%02d')];
            app.payArrivalTime_3.Text = [num2str(floor(ret(1).arrival_time/60), '%02d'),':',num2str(mod(ret(1).arrival_time,60), '%02d')];
            app.payWhetherTransfer_3.Text = ret(1).transfer_station;
            if app.price_gap >= ret(1).price
                p = 0;
            else
                p = ret(1).price - app.price_gap;
            end
            app.payPrice_3.Text = num2str(p);
        end

        % Button pushed function: Buy_Button_6
        function Buy_Button_6Pushed(app, event)
            app.rescheduleSearch.Visible = false;
            app.reschedulePay.Visible = true;
            app.consult.setIndex(2);
            ret = app.consult.lastArray;
            app.payDepartureStation_3.Text = num2str(ret(2).departure_station);
            app.payArrivalStation_3.Text = num2str(ret(2).arrival_station);
            app.payDepartureTime_3.Text = [num2str(floor(ret(2).departure_time/60), '%02d'),':',num2str(mod(ret(2).departure_time,60), '%02d')];
            app.payArrivalTime_3.Text = [num2str(floor(ret(2).arrival_time/60), '%02d'),':',num2str(mod(ret(2).arrival_time,60), '%02d')];
            app.payWhetherTransfer_3.Text = ret(2).transfer_station;
            if app.price_gap >= ret(2).price
                p = 0;
            else
                p = ret(2).price - app.price_gap;
            end
            app.payPrice_3.Text = num2str(p);
        end

        % Button pushed function: Buy_Button_7
        function Buy_Button_7Pushed(app, event)
            app.rescheduleSearch.Visible = false;
            app.reschedulePay.Visible = true;
            app.consult.setIndex(3);
            ret = app.consult.lastArray;
            app.payDepartureStation_3.Text = num2str(ret(3).departure_station);
            app.payArrivalStation_3.Text = num2str(ret(3).arrival_station);
            app.payDepartureTime_3.Text = [num2str(floor(ret(3).departure_time/60), '%02d'),':',num2str(mod(ret(3).departure_time,60), '%02d')];
            app.payArrivalTime_3.Text = [num2str(floor(ret(3).arrival_time/60), '%02d'),':',num2str(mod(ret(3).arrival_time,60), '%02d')];
            app.payWhetherTransfer_3.Text = ret(3).transfer_station;
            if app.price_gap >= ret(3).price
                p = 0;
            else
                p = ret(3).price - app.price_gap;
            end
            app.payPrice_3.Text = num2str(p);
        end

        % Button pushed function: Buy_Button_8
        function Buy_Button_8Pushed(app, event)
            app.rescheduleSearch.Visible = false;
            app.reschedulePay.Visible = true;
            app.consult.setIndex(4);
            ret = app.consult.lastArray;
            app.payDepartureStation_3.Text = num2str(ret(4).departure_station);
            app.payArrivalStation_3.Text = num2str(ret(4).arrival_station);
            app.payDepartureTime_3.Text = [num2str(floor(ret(4).departure_time/60), '%02d'),':',num2str(mod(ret(4).departure_time,60), '%02d')];
            app.payArrivalTime_3.Text = [num2str(floor(ret(4).arrival_time/60), '%02d'),':',num2str(mod(ret(4).arrival_time,60), '%02d')];
            app.payWhetherTransfer_3.Text = ret(4).transfer_station;
            if app.price_gap >= ret(4).price
                p = 0;
            else
                p = ret(4).price - app.price_gap;
            end
            app.payPrice_3.Text = num2str(p);
        end

        % Button pushed function: buyBackToHome_2
        function buyBackToHome_2ButtonPushed(app, event)
            app.reschedulePay.Visible = false;
            app.MineAfterLogin.Visible = true;
        end

        % Button pushed function: successButton
        function successButtonButtonPushed(app, event)
            app.SuccessPanel.Visible = false;
            app.FrontPage.Visible = true;
        end

        % Button pushed function: buyAssure_2
        function buyAssure_2ButtonPushed(app, event)
            success1 = app.account.tryCancelTicket();
            
            structIn = app.consult.lastArray(app.consult.index);
            input = app.buyChooseSeat_2.Value;
            seat_quality = app.convertSeatQuality(input);
            success2 = app.account.buyTicket(structIn, seat_quality);
            if app.account.logInAccountId == -1
                msgbox('Error', 'Please log in', 'error');
            end
            success = success1 & success2;
            if success
                app.reschedulePay.Visible = false;
                app.SuccessPanel.Visible = true;
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.LeapTimePanel.Visible = false;
            app.payPanel.Visible = false;
            app.FrontPage.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CounterUIFigure = uifigure('Visible', 'off');
            app.CounterUIFigure.Name = 'Counter';
            app.CounterUIFigure.CloseRequestFcn = createCallbackFcn(app, @CounterUIFigureCloseRequest, true);
            app.TimeControllerPanel = uipanel(app.CounterUIFigure);
            app.TimeControllerPanel.Title = 'Time Controller';
            app.timeContinue = uibutton(app.TimeControllerPanel, 'push');
            app.timeContinue.ButtonPushedFcn = createCallbackFcn(app, @timeContinueButtonPushed, true);
            app.timeContinue.Text = '继续';
            app.timeStop = uibutton(app.TimeControllerPanel, 'push');
            app.timeStop.ButtonPushedFcn = createCallbackFcn(app, @timeStopButtonPushed, true);
            app.timeStop.Text = '暂停';
            app.EditField_3 = uieditfield(app.TimeControllerPanel, 'numeric');
            app.EditField_3.Value = 1;
            app.Label_36 = uilabel(app.TimeControllerPanel);
            app.Label_36.Text = '时间流速';
            app.setTimeSpeed = uibutton(app.TimeControllerPanel, 'push');
            app.setTimeSpeed.ButtonPushedFcn = createCallbackFcn(app, @setTimeSpeedButtonPushed, true);
            app.setTimeSpeed.Text = '设定';
            app.showTimeHou = uilabel(app.TimeControllerPanel);
            app.showTimeHou.Text = '00';
            app.showTimeMin = uilabel(app.TimeControllerPanel);
            app.showTimeMin.Text = '00';
            app.Label_76 = uilabel(app.TimeControllerPanel);
            app.Label_76.Text = ':';
            app.Label_79 = uilabel(app.TimeControllerPanel);
            app.Label_79.Text = '当前时间';
            app.Image = uiimage(app.CounterUIFigure);
            app.Image.ImageSource = 'railway.png';
            app.LampNangJing = uilamp(app.CounterUIFigure);
            app.LampChangZhou = uilamp(app.CounterUIFigure);
            app.LampSuZhou = uilamp(app.CounterUIFigure);
            app.LampShangHai = uilamp(app.CounterUIFigure);
            app.LampJiaXing = uilamp(app.CounterUIFigure);
            app.LampHangZhou = uilamp(app.CounterUIFigure);
            app.LampHuZhou = uilamp(app.CounterUIFigure);
            app.LampLiYang = uilamp(app.CounterUIFigure);
            app.UITable = uitable(app.CounterUIFigure);
            app.UITable.ColumnName = {'车站'; '车号'; '一等座'; '二等座'};
            app.UITable.RowName = {};
            app.NanJingNanLabel = uilabel(app.CounterUIFigure);
            app.NanJingNanLabel.Text = '  ';
            app.ChangZhouBeiLabel = uilabel(app.CounterUIFigure);
            app.ChangZhouBeiLabel.Text = ' ';
            app.SuZhouBeiLabel = uilabel(app.CounterUIFigure);
            app.SuZhouBeiLabel.Text = ' ';
            app.ShangHaiLabel = uilabel(app.CounterUIFigure);
            app.ShangHaiLabel.Text = '  ';
            app.JiaXingNanLabel = uilabel(app.CounterUIFigure);
            app.JiaXingNanLabel.Text = '  ';
            app.HangZhouDongLabel = uilabel(app.CounterUIFigure);
            app.HangZhouDongLabel.Text = '   ';
            app.HuZhouLabel = uilabel(app.CounterUIFigure);
            app.HuZhouLabel.Text = '   ';
            app.LiYangLabel = uilabel(app.CounterUIFigure);
            app.LiYangLabel.Text = '   ';
            app.Li_Nan = uilamp(app.CounterUIFigure);
            app.Nan_Chang = uilamp(app.CounterUIFigure);
            app.Li_Hu = uilamp(app.CounterUIFigure);
            app.Chang_Su = uilamp(app.CounterUIFigure);
            app.Hu_Hang = uilamp(app.CounterUIFigure);
            app.Su_Shang = uilamp(app.CounterUIFigure);
            app.Shang_Jia = uilamp(app.CounterUIFigure);
            app.Jia_Hang = uilamp(app.CounterUIFigure);
            app.Jia_Hang_Label = uilabel(app.CounterUIFigure);
            app.Jia_Hang_Label.Text = '   ';
            app.Hu_Hang_Label = uilabel(app.CounterUIFigure);
            app.Hu_Hang_Label.Text = '  ';
            app.Li_Hu_Label = uilabel(app.CounterUIFigure);
            app.Li_Hu_Label.Text = '  ';
            app.Li_Nan_Label = uilabel(app.CounterUIFigure);
            app.Li_Nan_Label.Text = '  ';
            app.Nan_Chang_Label = uilabel(app.CounterUIFigure);
            app.Nan_Chang_Label.Text = ' ';
            app.Chang_Su_Label = uilabel(app.CounterUIFigure);
            app.Chang_Su_Label.Text = ' ';
            app.Su_Shang_Label = uilabel(app.CounterUIFigure);
            app.Su_Shang_Label.Text = ' ';
            app.Shang_Jia_Label = uilabel(app.CounterUIFigure);
            app.Shang_Jia_Label.Text = '  ';
            app.Su_Chang_Label = uilabel(app.CounterUIFigure);
            app.Su_Chang_Label.Text = ' ';
            app.Shang_Su_Label = uilabel(app.CounterUIFigure);
            app.Shang_Su_Label.Text = ' ';
            app.Jia_Shang_Label = uilabel(app.CounterUIFigure);
            app.Jia_Shang_Label.Text = '  ';
            app.Hang_Jia_Label = uilabel(app.CounterUIFigure);
            app.Hang_Jia_Label.Text = '   ';
            app.Chang_Nan_Label = uilabel(app.CounterUIFigure);
            app.Chang_Nan_Label.Text = '  ';
            app.Nan_Li_Label = uilabel(app.CounterUIFigure);
            app.Nan_Li_Label.Text = '  ';
            app.Hu_Li_Label = uilabel(app.CounterUIFigure);
            app.Hu_Li_Label.Text = ' ';
            app.Hang_Hu_Label = uilabel(app.CounterUIFigure);
            app.Hang_Hu_Label.Text = '  ';
            app.MineAfterLogin = uipanel(app.CounterUIFigure);
            app.MineAfterLogin.Title = 'Mine';
            app.Button_11 = uibutton(app.MineAfterLogin, 'push');
            app.Button_11.Text = '我的';
            app.Button_12 = uibutton(app.MineAfterLogin, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '首页';
            app.Label_7 = uilabel(app.MineAfterLogin);
            app.Label_7.Text = '已登录';
            app.logout = uibutton(app.MineAfterLogin, 'push');
            app.logout.ButtonPushedFcn = createCallbackFcn(app, @logoutPushed, true);
            app.logout.Text = '登出';
            app.LabelUser = uilabel(app.MineAfterLogin);
            app.LabelUser.Text = '用户';
            app.LabelLogInId = uilabel(app.MineAfterLogin);
            app.LabelLogInId.Text = '0';
            app.MyTicket = uibutton(app.MineAfterLogin, 'push');
            app.MyTicket.ButtonPushedFcn = createCallbackFcn(app, @MyTicketButtonPushed, true);
            app.MyTicket.Text = '本人车票';
            app.NoTicketPanel = uipanel(app.MineAfterLogin);
            app.Button_13 = uibutton(app.NoTicketPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '确定';
            app.Label_43 = uilabel(app.NoTicketPanel);
            app.Label_43.Text = '您没有已购买的车票';
            app.rescheduleSearch = uipanel(app.CounterUIFigure);
            app.rescheduleSearch.Title = 'reschedule';
            app.Button_14 = uibutton(app.rescheduleSearch, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '<返回';
            app.Panel_8 = uipanel(app.rescheduleSearch);
            app.departure_station_5 = uilabel(app.Panel_8);
            app.arrival_station_5 = uilabel(app.Panel_8);
            app.departure_time_5 = uilabel(app.Panel_8);
            app.arrival_time_5 = uilabel(app.Panel_8);
            app.Label_54 = uilabel(app.Panel_8);
            app.Label_54.Text = '始';
            app.Label_55 = uilabel(app.Panel_8);
            app.Label_55.Text = '终';
            app.Label_56 = uilabel(app.Panel_8);
            app.Label_56.Text = '需转乘：';
            app.transfer_station_5 = uilabel(app.Panel_8);
            app.transfer_station_5.Text = 'Label2';
            app.Label_57 = uilabel(app.Panel_8);
            app.Label_57.Text = '总耗时：';
            app.interval_time_5 = uilabel(app.Panel_8);
            app.interval_time_5.Text = 'Label3';
            app.Label_58 = uilabel(app.Panel_8);
            app.Label_58.Text = '价格：';
            app.price_5 = uilabel(app.Panel_8);
            app.price_5.Text = 'Label4';
            app.train1_5 = uilabel(app.Panel_8);
            app.train1_5.Text = 'Label5';
            app.train2_5 = uilabel(app.Panel_8);
            app.train2_5.Text = 'Label6';
            app.Panel_9 = uipanel(app.rescheduleSearch);
            app.departure_station_6 = uilabel(app.Panel_9);
            app.arrival_station_6 = uilabel(app.Panel_9);
            app.departure_time_6 = uilabel(app.Panel_9);
            app.arrival_time_6 = uilabel(app.Panel_9);
            app.Label_59 = uilabel(app.Panel_9);
            app.Label_59.Text = '始';
            app.Label_60 = uilabel(app.Panel_9);
            app.Label_60.Text = '终';
            app.Label_61 = uilabel(app.Panel_9);
            app.Label_61.Text = '需转乘：';
            app.transfer_station_6 = uilabel(app.Panel_9);
            app.transfer_station_6.Text = 'Label2';
            app.Label_62 = uilabel(app.Panel_9);
            app.Label_62.Text = '总耗时：';
            app.interval_time_6 = uilabel(app.Panel_9);
            app.interval_time_6.Text = 'Label3';
            app.Label_63 = uilabel(app.Panel_9);
            app.Label_63.Text = '价格：';
            app.price_6 = uilabel(app.Panel_9);
            app.price_6.Text = 'Label4';
            app.train1_6 = uilabel(app.Panel_9);
            app.train1_6.Text = 'Label5';
            app.train2_6 = uilabel(app.Panel_9);
            app.train2_6.Text = 'Label6';
            app.Panel_10 = uipanel(app.rescheduleSearch);
            app.departure_station_7 = uilabel(app.Panel_10);
            app.arrival_station_7 = uilabel(app.Panel_10);
            app.departure_time_7 = uilabel(app.Panel_10);
            app.arrival_time_7 = uilabel(app.Panel_10);
            app.Label_64 = uilabel(app.Panel_10);
            app.Label_64.Text = '始';
            app.Label_65 = uilabel(app.Panel_10);
            app.Label_65.Text = '终';
            app.Label_66 = uilabel(app.Panel_10);
            app.Label_66.Text = '需转乘：';
            app.transfer_station_7 = uilabel(app.Panel_10);
            app.transfer_station_7.Text = 'Label2';
            app.Label_67 = uilabel(app.Panel_10);
            app.Label_67.Text = '总耗时：';
            app.interval_time_7 = uilabel(app.Panel_10);
            app.interval_time_7.Text = 'Label3';
            app.Label_68 = uilabel(app.Panel_10);
            app.Label_68.Text = '价格：';
            app.price_7 = uilabel(app.Panel_10);
            app.price_7.Text = 'Label4';
            app.train1_7 = uilabel(app.Panel_10);
            app.train1_7.Text = 'Label5';
            app.train2_7 = uilabel(app.Panel_10);
            app.train2_7.Text = 'Label6';
            app.Panel_11 = uipanel(app.rescheduleSearch);
            app.departure_station_8 = uilabel(app.Panel_11);
            app.arrival_station_8 = uilabel(app.Panel_11);
            app.departure_time_8 = uilabel(app.Panel_11);
            app.arrival_time_8 = uilabel(app.Panel_11);
            app.Label_69 = uilabel(app.Panel_11);
            app.Label_69.Text = '始';
            app.Label_70 = uilabel(app.Panel_11);
            app.Label_70.Text = '终';
            app.Label_71 = uilabel(app.Panel_11);
            app.Label_71.Text = '需转乘：';
            app.transfer_station_8 = uilabel(app.Panel_11);
            app.transfer_station_8.Text = 'Label2';
            app.Label_72 = uilabel(app.Panel_11);
            app.Label_72.Text = '总耗时：';
            app.interval_time_8 = uilabel(app.Panel_11);
            app.interval_time_8.Text = 'Label3';
            app.Label_73 = uilabel(app.Panel_11);
            app.Label_73.Text = '价格：';
            app.price_8 = uilabel(app.Panel_11);
            app.price_8.Text = 'Label4';
            app.train1_8 = uilabel(app.Panel_11);
            app.train1_8.Text = 'Label5';
            app.train2_8 = uilabel(app.Panel_11);
            app.train2_8.Text = 'Label6';
            app.Buy_Button_5 = uibutton(app.rescheduleSearch, 'push');
            app.Buy_Button_5.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_5Pushed, true);
            app.Buy_Button_5.Text = '购买';
            app.Buy_Button_6 = uibutton(app.rescheduleSearch, 'push');
            app.Buy_Button_6.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_6Pushed, true);
            app.Buy_Button_6.Text = '购买';
            app.Buy_Button_7 = uibutton(app.rescheduleSearch, 'push');
            app.Buy_Button_7.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_7Pushed, true);
            app.Buy_Button_7.Text = '购买';
            app.Buy_Button_8 = uibutton(app.rescheduleSearch, 'push');
            app.Buy_Button_8.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_8Pushed, true);
            app.Buy_Button_8.Text = '购买';
            app.Label_46 = uilabel(app.rescheduleSearch);
            app.Label_46.Text = '一等座：';
            app.Label_47 = uilabel(app.rescheduleSearch);
            app.Label_47.Text = '二等座：';
            app.firstclass_5 = uilabel(app.rescheduleSearch);
            app.firstclass_5.Text = '0';
            app.secondclass_5 = uilabel(app.rescheduleSearch);
            app.secondclass_5.Text = '0';
            app.Label_48 = uilabel(app.rescheduleSearch);
            app.Label_48.Text = '一等座：';
            app.Label_49 = uilabel(app.rescheduleSearch);
            app.Label_49.Text = '二等座：';
            app.firstclass_6 = uilabel(app.rescheduleSearch);
            app.firstclass_6.Text = '0';
            app.secondclass_6 = uilabel(app.rescheduleSearch);
            app.secondclass_6.Text = '0';
            app.Label_50 = uilabel(app.rescheduleSearch);
            app.Label_50.Text = '一等座：';
            app.Label_51 = uilabel(app.rescheduleSearch);
            app.Label_51.Text = '二等座：';
            app.firstclass_7 = uilabel(app.rescheduleSearch);
            app.firstclass_7.Text = '0';
            app.secondclass_7 = uilabel(app.rescheduleSearch);
            app.secondclass_7.Text = '0';
            app.Label_52 = uilabel(app.rescheduleSearch);
            app.Label_52.Text = '一等座：';
            app.Label_53 = uilabel(app.rescheduleSearch);
            app.Label_53.Text = '二等座：';
            app.firstclass_8 = uilabel(app.rescheduleSearch);
            app.firstclass_8.Text = '0';
            app.secondclass_8 = uilabel(app.rescheduleSearch);
            app.secondclass_8.Text = '0';
            app.SuccessPanel = uipanel(app.CounterUIFigure);
            app.SuccessPanel.Title = 'Success';
            app.successLabel = uilabel(app.SuccessPanel);
            app.successLabel.Text = '完成！';
            app.successButton = uibutton(app.SuccessPanel, 'push');
            app.successButton.ButtonPushedFcn = createCallbackFcn(app, @successButtonButtonPushed, true);
            app.successButton.Text = '返回';
            app.reschedulePay = uipanel(app.CounterUIFigure);
            app.reschedulePay.Title = 'reschedule';
            app.buyPrice_3 = uilabel(app.reschedulePay);
            app.buyPrice_3.Text = '差价:¥';
            app.buyTicket_2 = uilabel(app.reschedulePay);
            app.buyTicket_2.Text = '购买车票';
            app.buyBackToHome_2 = uibutton(app.reschedulePay, 'push');
            app.buyBackToHome_2.ButtonPushedFcn = createCallbackFcn(app, @buyBackToHome_2ButtonPushed, true);
            app.buyBackToHome_2.Text = '返回';
            app.buyAssure_2 = uibutton(app.reschedulePay, 'push');
            app.buyAssure_2.ButtonPushedFcn = createCallbackFcn(app, @buyAssure_2ButtonPushed, true);
            app.buyAssure_2.Text = '确定';
            app.buyDepartureStation_3 = uilabel(app.reschedulePay);
            app.buyDepartureStation_3.Text = '始发站:';
            app.buyArrivalStation_3 = uilabel(app.reschedulePay);
            app.buyArrivalStation_3.Text = '到达站:';
            app.buyDepartureTime_3 = uilabel(app.reschedulePay);
            app.buyDepartureTime_3.Text = '发车时间:';
            app.buyArrivalTime_3 = uilabel(app.reschedulePay);
            app.buyArrivalTime_3.Text = '到达时间:';
            app.buyWhetherTransfer_3 = uilabel(app.reschedulePay);
            app.buyWhetherTransfer_3.Text = '是否换乘:';
            app.Label_74 = uilabel(app.reschedulePay);
            app.Label_74.Text = '座位类型';
            app.buyChooseSeat_2 = uidropdown(app.reschedulePay);
            app.buyChooseSeat_2.Items = {'一等座', '二等座'};
            app.buyChooseSeat_2.Value = '二等座';
            app.payPrice_3 = uilabel(app.reschedulePay);
            app.payPrice_3.Text = '始发站:';
            app.payDepartureStation_3 = uilabel(app.reschedulePay);
            app.payDepartureStation_3.Text = '始发站:';
            app.payDepartureTime_3 = uilabel(app.reschedulePay);
            app.payDepartureTime_3.Text = '始发站:';
            app.payArrivalStation_3 = uilabel(app.reschedulePay);
            app.payArrivalStation_3.Text = '始发站:';
            app.payArrivalTime_3 = uilabel(app.reschedulePay);
            app.payArrivalTime_3.Text = '始发站:';
            app.payWhetherTransfer_3 = uilabel(app.reschedulePay);
            app.payWhetherTransfer_3.Text = '始发站:';
            app.Label_75 = uilabel(app.reschedulePay);
            app.Label_75.Text = '请补齐差价';
            app.rescheduleFront = uipanel(app.CounterUIFigure);
            app.rescheduleFront.Title = 'reschedule ';
            app.Button_re_search = uibutton(app.rescheduleFront, 'push');
            app.Button_re_search.ButtonPushedFcn = createCallbackFcn(app, @Button_re_searchPushed, true);
            app.Button_re_search.Text = '查询';
            app.DropDown_3Label = uilabel(app.rescheduleFront);
            app.DropDown_3Label.Text = '从';
            app.DropDown_re_from = uidropdown(app.rescheduleFront);
            app.DropDown_re_from.Items = {'杭州东', '嘉兴南', '湖州', '上海虹桥', '苏州北', '溧阳', '常州北', '南京南'};
            app.DropDown_re_from.Value = '杭州东';
            app.DropDown_4Label = uilabel(app.rescheduleFront);
            app.DropDown_4Label.Text = '去往';
            app.DropDown_re_to = uidropdown(app.rescheduleFront);
            app.DropDown_re_to.Items = {'杭州东', '嘉兴南', '湖州', '上海虹桥', '苏州北', '溧阳', '常州北', '南京南'};
            app.DropDown_re_to.Value = '苏州北';
            app.Label_45 = uilabel(app.rescheduleFront);
            app.Label_45.Text = '排序';
            app.sort_way_re = uidropdown(app.rescheduleFront);
            app.sort_way_re.Items = {'最少耗时', '最早到时', '最小价格'};
            app.sort_way_re.Value = '最少耗时';
            app.Button_re_back = uibutton(app.rescheduleFront, 'push');
            app.Button_re_back.ButtonPushedFcn = createCallbackFcn(app, @Button_re_backPushed, true);
            app.Button_re_back.Text = '<返回';
            app.MineBeforeLogin = uipanel(app.CounterUIFigure);
            app.MineBeforeLogin.Title = 'Mine';
            app.Label_6 = uilabel(app.MineBeforeLogin);
            app.Label_6.Text = '用户';
            app.accountId = uieditfield(app.MineBeforeLogin, 'numeric');
            app.accountId.Limits = [1 Inf];
            app.accountId.ValueDisplayFormat = '%.0f';
            app.accountId.Value = 114514;
            app.login = uibutton(app.MineBeforeLogin, 'push');
            app.login.ButtonPushedFcn = createCallbackFcn(app, @loginPushed, true);
            app.login.Text = '登陆';
            app.Button_8 = uibutton(app.MineBeforeLogin, 'push');
            app.Button_8.Text = '我的';
            app.Button_9 = uibutton(app.MineBeforeLogin, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '首页';
            app.TicketPanel = uipanel(app.CounterUIFigure);
            app.TicketPanel.Title = 'ticket';
            app.Label_32 = uilabel(app.TicketPanel);
            app.Label_32.Text = '已登录';
            app.LabelUser_2 = uilabel(app.TicketPanel);
            app.LabelUser_2.Text = '用户';
            app.LabelLogInId_2 = uilabel(app.TicketPanel);
            app.LabelLogInId_2.Text = '0';
            app.ticketBackToMine = uibutton(app.TicketPanel, 'push');
            app.ticketBackToMine.ButtonPushedFcn = createCallbackFcn(app, @ticketBackToMineButtonPushed, true);
            app.ticketBackToMine.Text = '<返回';
            app.tryCancelTicket = uibutton(app.TicketPanel, 'push');
            app.tryCancelTicket.ButtonPushedFcn = createCallbackFcn(app, @tryCancelTicketPushed, true);
            app.tryCancelTicket.Text = '退票';
            app.Label_35 = uilabel(app.TicketPanel);
            app.Label_35.Text = '';
            app.buyPrice_2 = uilabel(app.TicketPanel);
            app.buyPrice_2.Text = '价格:¥';
            app.buyDepartureStation_2 = uilabel(app.TicketPanel);
            app.buyDepartureStation_2.Text = '始发站:';
            app.buyArrivalStation_2 = uilabel(app.TicketPanel);
            app.buyArrivalStation_2.Text = '到达站:';
            app.buyDepartureTime_2 = uilabel(app.TicketPanel);
            app.buyDepartureTime_2.Text = '发车时间:';
            app.buyArrivalTime_2 = uilabel(app.TicketPanel);
            app.buyArrivalTime_2.Text = '到达时间:';
            app.payPrice_2 = uilabel(app.TicketPanel);
            app.payPrice_2.Text = '始发站:';
            app.payDepartureStation_2 = uilabel(app.TicketPanel);
            app.payDepartureStation_2.Text = '始发站:';
            app.payDepartureTime_2 = uilabel(app.TicketPanel);
            app.payDepartureTime_2.Text = '始发站:';
            app.payArrivalStation_2 = uilabel(app.TicketPanel);
            app.payArrivalStation_2.Text = '始发站:';
            app.payArrivalTime_2 = uilabel(app.TicketPanel);
            app.payArrivalTime_2.Text = '始发站:';
            app.buySeatQuality_2 = uilabel(app.TicketPanel);
            app.buySeatQuality_2.Text = '座位类型：';
            app.buyWhetherTransfer_2 = uilabel(app.TicketPanel);
            app.buyWhetherTransfer_2.Text = '是否中转：';
            app.paySeatQuality_2 = uilabel(app.TicketPanel);
            app.paySeatQuality_2.Text = '始发站:';
            app.payWhetherTransfer_2 = uilabel(app.TicketPanel);
            app.payWhetherTransfer_2.Text = '始发站:';
            app.reassure_cancel_ticket = uipanel(app.TicketPanel);
            app.Label_33 = uilabel(app.reassure_cancel_ticket);
            app.Label_33.Text = '确定退票？';
            app.cancelCancelTicket = uibutton(app.reassure_cancel_ticket, 'push');
            app.cancelCancelTicket.ButtonPushedFcn = createCallbackFcn(app, @cancelCancelTicketButtonPushed, true);
            app.cancelCancelTicket.Text = '取消';
            app.cancelTicket = uibutton(app.reassure_cancel_ticket, 'push');
            app.cancelTicket.ButtonPushedFcn = createCallbackFcn(app, @cancelTicketButtonPushed, true);
            app.cancelTicket.Text = '确定';
            app.RescheduleButton = uibutton(app.TicketPanel, 'push');
            app.RescheduleButton.ButtonPushedFcn = createCallbackFcn(app, @RescheduleButtonPushed, true);
            app.RescheduleButton.Text = '改签';
            app.boardButton = uibutton(app.TicketPanel, 'state');
            app.boardButton.Text = '上车';
            app.searchPanel = uipanel(app.CounterUIFigure);
            app.searchPanel.Title = 'search';
            app.Button_6 = uibutton(app.searchPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '<返回';
            app.Panel = uipanel(app.searchPanel);
            app.departure_station = uilabel(app.Panel);
            app.arrival_station = uilabel(app.Panel);
            app.departure_time = uilabel(app.Panel);
            app.arrival_time = uilabel(app.Panel);
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '始';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '终';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '需转乘：';
            app.transfer_station = uilabel(app.Panel);
            app.transfer_station.Text = 'Label2';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '总耗时：';
            app.interval_time = uilabel(app.Panel);
            app.interval_time.Text = 'Label3';
            app.Label_25 = uilabel(app.Panel);
            app.Label_25.Text = '价格：';
            app.price = uilabel(app.Panel);
            app.price.Text = 'Label4';
            app.train1 = uilabel(app.Panel);
            app.train1.Text = 'Label5';
            app.train2 = uilabel(app.Panel);
            app.train2.Text = 'Label6';
            app.Panel_5 = uipanel(app.searchPanel);
            app.departure_station_2 = uilabel(app.Panel_5);
            app.arrival_station_2 = uilabel(app.Panel_5);
            app.departure_time_2 = uilabel(app.Panel_5);
            app.arrival_time_2 = uilabel(app.Panel_5);
            app.Label_12 = uilabel(app.Panel_5);
            app.Label_12.Text = '始';
            app.Label_13 = uilabel(app.Panel_5);
            app.Label_13.Text = '终';
            app.Label_14 = uilabel(app.Panel_5);
            app.Label_14.Text = '需转乘：';
            app.transfer_station_2 = uilabel(app.Panel_5);
            app.transfer_station_2.Text = 'Label2';
            app.Label_15 = uilabel(app.Panel_5);
            app.Label_15.Text = '总耗时：';
            app.interval_time_2 = uilabel(app.Panel_5);
            app.interval_time_2.Text = 'Label3';
            app.Label_26 = uilabel(app.Panel_5);
            app.Label_26.Text = '价格：';
            app.price_2 = uilabel(app.Panel_5);
            app.price_2.Text = 'Label4';
            app.train1_2 = uilabel(app.Panel_5);
            app.train1_2.Text = 'Label5';
            app.train2_2 = uilabel(app.Panel_5);
            app.train2_2.Text = 'Label6';
            app.Panel_6 = uipanel(app.searchPanel);
            app.departure_station_3 = uilabel(app.Panel_6);
            app.arrival_station_3 = uilabel(app.Panel_6);
            app.departure_time_3 = uilabel(app.Panel_6);
            app.arrival_time_3 = uilabel(app.Panel_6);
            app.Label_16 = uilabel(app.Panel_6);
            app.Label_16.Text = '始';
            app.Label_17 = uilabel(app.Panel_6);
            app.Label_17.Text = '终';
            app.Label_18 = uilabel(app.Panel_6);
            app.Label_18.Text = '需转乘：';
            app.transfer_station_3 = uilabel(app.Panel_6);
            app.transfer_station_3.Text = 'Label2';
            app.Label_19 = uilabel(app.Panel_6);
            app.Label_19.Text = '总耗时：';
            app.interval_time_3 = uilabel(app.Panel_6);
            app.interval_time_3.Text = 'Label3';
            app.Label_27 = uilabel(app.Panel_6);
            app.Label_27.Text = '价格：';
            app.price_3 = uilabel(app.Panel_6);
            app.price_3.Text = 'Label4';
            app.train1_3 = uilabel(app.Panel_6);
            app.train1_3.Text = 'Label5';
            app.train2_3 = uilabel(app.Panel_6);
            app.train2_3.Text = 'Label6';
            app.Panel_7 = uipanel(app.searchPanel);
            app.departure_station_4 = uilabel(app.Panel_7);
            app.arrival_station_4 = uilabel(app.Panel_7);
            app.departure_time_4 = uilabel(app.Panel_7);
            app.arrival_time_4 = uilabel(app.Panel_7);
            app.Label_20 = uilabel(app.Panel_7);
            app.Label_20.Text = '始';
            app.Label_21 = uilabel(app.Panel_7);
            app.Label_21.Text = '终';
            app.Label_22 = uilabel(app.Panel_7);
            app.Label_22.Text = '需转乘：';
            app.transfer_station_4 = uilabel(app.Panel_7);
            app.transfer_station_4.Text = 'Label2';
            app.Label_23 = uilabel(app.Panel_7);
            app.Label_23.Text = '总耗时：';
            app.interval_time_4 = uilabel(app.Panel_7);
            app.interval_time_4.Text = 'Label3';
            app.Label_28 = uilabel(app.Panel_7);
            app.Label_28.Text = '价格：';
            app.price_4 = uilabel(app.Panel_7);
            app.price_4.Text = 'Label4';
            app.train1_4 = uilabel(app.Panel_7);
            app.train1_4.Text = 'Label5';
            app.train2_4 = uilabel(app.Panel_7);
            app.train2_4.Text = 'Label6';
            app.Buy_Button_1 = uibutton(app.searchPanel, 'push');
            app.Buy_Button_1.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_1Pushed, true);
            app.Buy_Button_1.Text = '购买';
            app.Buy_Button_2 = uibutton(app.searchPanel, 'push');
            app.Buy_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_2Pushed, true);
            app.Buy_Button_2.Text = '购买';
            app.Buy_Button_3 = uibutton(app.searchPanel, 'push');
            app.Buy_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_3Pushed, true);
            app.Buy_Button_3.Text = '购买';
            app.Buy_Button_4 = uibutton(app.searchPanel, 'push');
            app.Buy_Button_4.ButtonPushedFcn = createCallbackFcn(app, @Buy_Button_4Pushed, true);
            app.Buy_Button_4.Text = '购买';
            app.Label_30 = uilabel(app.searchPanel);
            app.Label_30.Text = '一等座：';
            app.Label_31 = uilabel(app.searchPanel);
            app.Label_31.Text = '二等座：';
            app.firstclass_1 = uilabel(app.searchPanel);
            app.firstclass_1.Text = '0';
            app.secondclass_1 = uilabel(app.searchPanel);
            app.secondclass_1.Text = '0';
            app.Label_37 = uilabel(app.searchPanel);
            app.Label_37.Text = '一等座：';
            app.Label_38 = uilabel(app.searchPanel);
            app.Label_38.Text = '二等座：';
            app.firstclass_2 = uilabel(app.searchPanel);
            app.firstclass_2.Text = '0';
            app.secondclass_2 = uilabel(app.searchPanel);
            app.secondclass_2.Text = '0';
            app.Label_39 = uilabel(app.searchPanel);
            app.Label_39.Text = '一等座：';
            app.Label_40 = uilabel(app.searchPanel);
            app.Label_40.Text = '二等座：';
            app.firstclass_3 = uilabel(app.searchPanel);
            app.firstclass_3.Text = '0';
            app.secondclass_3 = uilabel(app.searchPanel);
            app.secondclass_3.Text = '0';
            app.Label_41 = uilabel(app.searchPanel);
            app.Label_41.Text = '一等座：';
            app.Label_42 = uilabel(app.searchPanel);
            app.Label_42.Text = '二等座：';
            app.firstclass_4 = uilabel(app.searchPanel);
            app.firstclass_4.Text = '0';
            app.secondclass_4 = uilabel(app.searchPanel);
            app.secondclass_4.Text = '0';
            app.payPanel = uipanel(app.CounterUIFigure);
            app.payPanel.Title = 'pay';
            app.buyPrice = uilabel(app.payPanel);
            app.buyPrice.Text = '价格:¥';
            app.buyTicket = uilabel(app.payPanel);
            app.buyTicket.Text = '购买车票';
            app.buyBackToHome = uibutton(app.payPanel, 'push');
            app.buyBackToHome.ButtonPushedFcn = createCallbackFcn(app, @buyBackToHomeButtonPushed, true);
            app.buyBackToHome.Text = '返回首页';
            app.buyAssure = uibutton(app.payPanel, 'push');
            app.buyAssure.ButtonPushedFcn = createCallbackFcn(app, @buyAssureButtonPushed, true);
            app.buyAssure.Text = '确定';
            app.buyDepartureStation = uilabel(app.payPanel);
            app.buyDepartureStation.Text = '始发站:';
            app.buyArrivalStation = uilabel(app.payPanel);
            app.buyArrivalStation.Text = '到达站:';
            app.buyDepartureTime = uilabel(app.payPanel);
            app.buyDepartureTime.Text = '发车时间:';
            app.buyArrivalTime = uilabel(app.payPanel);
            app.buyArrivalTime.Text = '到达时间:';
            app.buyWhetherTransfer = uilabel(app.payPanel);
            app.buyWhetherTransfer.Text = '是否换乘:';
            app.Label_29 = uilabel(app.payPanel);
            app.Label_29.Text = '座位类型';
            app.buyChooseSeat = uidropdown(app.payPanel);
            app.buyChooseSeat.Items = {'一等座', '二等座'};
            app.buyChooseSeat.Value = '二等座';
            app.payPrice = uilabel(app.payPanel);
            app.payPrice.Text = '始发站:';
            app.payDepartureStation = uilabel(app.payPanel);
            app.payDepartureStation.Text = '始发站:';
            app.payDepartureTime = uilabel(app.payPanel);
            app.payDepartureTime.Text = '始发站:';
            app.payArrivalStation = uilabel(app.payPanel);
            app.payArrivalStation.Text = '始发站:';
            app.payArrivalTime = uilabel(app.payPanel);
            app.payArrivalTime.Text = '始发站:';
            app.payWhetherTransfer = uilabel(app.payPanel);
            app.payWhetherTransfer.Text = '始发站:';
            app.Label_81 = uilabel(app.payPanel);
            app.Label_81.Text = '支付剩余时间：';
            app.Label_82 = uilabel(app.payPanel);
            app.Label_82.Text = '分钟';
            app.payTimeLeft = uilabel(app.payPanel);
            app.payTimeLeft.Text = '15';
            app.LeapTimePanel = uipanel(app.payPanel);
            app.Button_15 = uibutton(app.LeapTimePanel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '返回首页';
            app.Label_83 = uilabel(app.LeapTimePanel);
            app.Label_83.Text = '您未在时间内支付，购买失败';
            app.FrontPage = uipanel(app.CounterUIFigure);
            app.FrontPage.Title = ' ';
            app.Button = uibutton(app.FrontPage, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '查询';
            app.frontPageMine = uibutton(app.FrontPage, 'push');
            app.frontPageMine.ButtonPushedFcn = createCallbackFcn(app, @frontPageMinePushed, true);
            app.frontPageMine.Text = '我的';
            app.Label_2 = uilabel(app.FrontPage);
            app.Label_2.Text = '从';
            app.DropDown = uidropdown(app.FrontPage);
            app.DropDown.Items = {'杭州东', '嘉兴南', '湖州', '上海虹桥', '苏州北', '溧阳', '常州北', '南京南'};
            app.DropDown.Value = '杭州东';
            app.DropDown_2Label = uilabel(app.FrontPage);
            app.DropDown_2Label.Text = '去往';
            app.DropDown_2 = uidropdown(app.FrontPage);
            app.DropDown_2.Items = {'杭州东', '嘉兴南', '湖州', '上海虹桥', '苏州北', '溧阳', '常州北', '南京南'};
            app.DropDown_2.Value = '苏州北';
            app.Label_3 = uilabel(app.FrontPage);
            app.Label_3.Text = '日期';
            app.DatePicker = uidatepicker(app.FrontPage);
            app.DatePicker.DisplayFormat = 'dd-MMM-uuuu';
            app.DatePicker.Value = datetime([2023 4 14]);
            app.frontPageFrontPage = uibutton(app.FrontPage, 'push');
            app.frontPageFrontPage.Text = '首页';
            app.Label_5 = uilabel(app.FrontPage);
            app.Label_5.Text = '：';
            app.Label_24 = uilabel(app.FrontPage);
            app.Label_24.Text = '排序';
            app.sort_way = uidropdown(app.FrontPage);
            app.sort_way.Items = {'最少耗时', '最早到时', '最小价格'};
            app.sort_way.Value = '最少耗时';
            app.EditField = uilabel(app.FrontPage);
            app.EditField.Text = '00';
            app.EditField_2 = uilabel(app.FrontPage);
            app.EditField_2.Text = '00';
            app.Label_80 = uilabel(app.FrontPage);
            app.Label_80.Text = '时间';
            % Show the figure after all components are created
            app.CounterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CounterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CounterUIFigure)
        end
    end
end